classdef dataprocessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup2                     matlab.ui.container.TabGroup
        TVSTab                        matlab.ui.container.Tab
        selectdataPanel               matlab.ui.container.Panel
        loadfileButton                matlab.ui.control.Button
        filepathEditFieldLabel        matlab.ui.control.Label
        filepathEditField             matlab.ui.control.EditField
        sheetEditFieldLabel           matlab.ui.control.Label
        sheetEditField                matlab.ui.control.NumericEditField
        Panel                         matlab.ui.container.Panel
        lineEditFieldLabel            matlab.ui.control.Label
        lineEditField                 matlab.ui.control.EditField
        lineEditField_2Label          matlab.ui.control.Label
        lineEditField_2               matlab.ui.control.EditField
        toEditFieldLabel              matlab.ui.control.Label
        toEditField                   matlab.ui.control.EditField
        toEditField_2Label            matlab.ui.control.Label
        toEditField_2                 matlab.ui.control.EditField
        voltagecolumnincapitalEditFieldLabel  matlab.ui.control.Label
        voltagecolumnincapitalEditField  matlab.ui.control.EditField
        currentcolumnincapitalEditFieldLabel  matlab.ui.control.Label
        currentcolumnincapitalEditField  matlab.ui.control.EditField
        voltageEditFieldLabel         matlab.ui.control.Label
        voltageEditField              matlab.ui.control.EditField
        currentEditFieldLabel         matlab.ui.control.Label
        currentEditField              matlab.ui.control.EditField
        UIAxes6                       matlab.ui.control.UIAxes
        plotButton                    matlab.ui.control.Button
        UIAxes7                       matlab.ui.control.UIAxes
        savedataPanel                 matlab.ui.container.Panel
        selectfolderButton            matlab.ui.control.Button
        filepathEditField_2Label      matlab.ui.control.Label
        filepathEditField_2           matlab.ui.control.EditField
        filenameEditFieldLabel        matlab.ui.control.Label
        filenameEditField             matlab.ui.control.EditField
        saveButton                    matlab.ui.control.Button
        transitionvoltageisEditFieldLabel  matlab.ui.control.Label
        transitionvoltageisEditField  matlab.ui.control.NumericEditField
        andEditFieldLabel             matlab.ui.control.Label
        andEditField                  matlab.ui.control.NumericEditField
        NDCTab                        matlab.ui.container.Tab
        selectdataPanel_2             matlab.ui.container.Panel
        loadfileButton_2              matlab.ui.control.Button
        filepathEditField_3Label      matlab.ui.control.Label
        filepathEditField_3           matlab.ui.control.EditField
        sheetEditField_2Label         matlab.ui.control.Label
        sheetEditField_2              matlab.ui.control.NumericEditField
        Panel_2                       matlab.ui.container.Panel
        lineEditField_3Label          matlab.ui.control.Label
        lineEditField_3               matlab.ui.control.EditField
        lineEditField_4Label          matlab.ui.control.Label
        lineEditField_4               matlab.ui.control.EditField
        toEditField_3Label            matlab.ui.control.Label
        toEditField_3                 matlab.ui.control.EditField
        toEditField_4Label            matlab.ui.control.Label
        toEditField_4                 matlab.ui.control.EditField
        voltagecolumnincapitalEditField_2Label  matlab.ui.control.Label
        voltagecolumnincapitalEditField_2  matlab.ui.control.EditField
        currentcolumnincapitalEditField_2Label  matlab.ui.control.Label
        currentcolumnincapitalEditField_2  matlab.ui.control.EditField
        voltageEditField_2Label       matlab.ui.control.Label
        voltageEditField_2            matlab.ui.control.EditField
        currentEditField_2Label       matlab.ui.control.Label
        currentEditField_2            matlab.ui.control.EditField
        UIAxes6_2                     matlab.ui.control.UIAxes
        plotButton_2                  matlab.ui.control.Button
        savedataPanel_2               matlab.ui.container.Panel
        selectfolderButton_2          matlab.ui.control.Button
        filepathEditField_4Label      matlab.ui.control.Label
        filepathEditField_4           matlab.ui.control.EditField
        filenameEditField_2Label      matlab.ui.control.Label
        filenameEditField_2           matlab.ui.control.EditField
        saveButton_2                  matlab.ui.control.Button
        UIAxes7_2                     matlab.ui.control.UIAxes
        homeButton                    matlab.ui.control.Button
        Image3                        matlab.ui.control.Image
        Image5                        matlab.ui.control.Image
    end

    
    properties (Access = private)
        fileTVS="www";
        fileNDC="www";
        filefittunneling="www";
        
        voltageTVS;
        currentTVS;
        voltageNDC;
        currentNDC;
        voltageNDC2;
        currentNDC2;
        currentdiffNDC;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function returnButtonPushed(app, event)
            delete(app.UIFigure);
            run cover.mlapp;
        end

        % Button pushed function: loadfileButton
        function loadfileButtonPushed(app, event)
            [filename,pathname]=uigetfile({'*.xls*','load data'});
            if isequal(filename,0)||isequal(pathname,0)
                errordlg("haven't select a file",'error');
                return;
            end
            file=strcat(pathname,filename);
            app.filepathEditField.Value=file;
        end

        % Value changed function: voltagecolumnincapitalEditField
        function voltagecolumnincapitalEditFieldValueChanged(app, event)
            value = app.voltagecolumnincapitalEditField.Value;
            app.voltageEditField.Value=strcat(app.voltagecolumnincapitalEditField.Value,app.lineEditField.Value,":",app.voltagecolumnincapitalEditField.Value,app.toEditField.Value);
            
        end

        % Value changed function: lineEditField
        function lineEditFieldValueChanged(app, event)
            value = app.lineEditField.Value;
            app.voltageEditField.Value=strcat(app.voltagecolumnincapitalEditField.Value,app.lineEditField.Value,":",app.voltagecolumnincapitalEditField.Value,app.toEditField.Value);
            
        end

        % Value changed function: toEditField
        function toEditFieldValueChanged(app, event)
            value = app.toEditField.Value;
            app.voltageEditField.Value=strcat(app.voltagecolumnincapitalEditField.Value,app.lineEditField.Value,":",app.voltagecolumnincapitalEditField.Value,app.toEditField.Value);
            
        end

        % Value changed function: currentcolumnincapitalEditField
        function currentcolumnincapitalEditFieldValueChanged(app, event)
            value = app.currentcolumnincapitalEditField.Value;
            app.currentEditField.Value=strcat(app.currentcolumnincapitalEditField.Value,app.lineEditField_2.Value,":",app.currentcolumnincapitalEditField.Value,app.toEditField_2.Value);
            
        end

        % Value changed function: lineEditField_2
        function lineEditField_2ValueChanged(app, event)
            value = app.lineEditField_2.Value;
            app.currentEditField.Value=strcat(app.currentcolumnincapitalEditField.Value,app.lineEditField_2.Value,":",app.currentcolumnincapitalEditField.Value,app.toEditField_2.Value);
            
        end

        % Value changed function: toEditField_2
        function toEditField_2ValueChanged(app, event)
            value = app.toEditField_2.Value;
            app.currentEditField.Value=strcat(app.currentcolumnincapitalEditField.Value,app.lineEditField_2.Value,":",app.currentcolumnincapitalEditField.Value,app.toEditField_2.Value);
            
        end

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            app.UIAxes6.cla;
            app.UIAxes7.cla;
            
            app.voltageTVS.Value=xlsread(app.filepathEditField.Value,app.sheetEditField.Value,app.voltageEditField.Value);
            app.currentTVS.Value=xlsread(app.filepathEditField.Value,app.sheetEditField.Value,app.currentEditField.Value);
            
            m=1;
            VV=app.voltageTVS.Value;
            for i=1:length(VV)
                if VV(i)>=0
                    m=i-1;
                    break;
                end
            end
            VVnegative=app.voltageTVS.Value(1:m);
            VVpositive=app.voltageTVS.Value(m+1:length(VV));
            
            IIpositive=app.currentTVS.Value(m+1:length(VV));
            IInegative=app.currentTVS.Value(1:m);
            
            Ip=find((log(abs(IIpositive./VVpositive.^2)))==min(log(abs(IIpositive./VVpositive.^2))));
            In=find((log(abs(IInegative./VVnegative.^2)))==min(log(abs(IInegative./VVnegative.^2))));
            
            app.transitionvoltageisEditField.Value=VVnegative(In);
            app.andEditField.Value=VVpositive(Ip);
            
            plot(app.UIAxes6,app.voltageTVS.Value,app.currentTVS.Value,'Color',[11/255 7/255 137/255],'LineWidth',3);
            hold(app.UIAxes6,"on");
            xlabel(app.UIAxes6,'Voltage/V','fontsize',18)
            ylabel(app.UIAxes6,'|\itI/|','fontsize',18)
            set(gca,'FontSize',18);
            hold(app.UIAxes6,"off");
            
            scatter(app.UIAxes7,1./app.voltageTVS.Value,log(abs(app.currentTVS.Value./app.voltageTVS.Value.^2)));
            hold(app.UIAxes7,"on");
            xlabel(app.UIAxes7,'1/Voltage/V','fontsize',18)
            ylabel(app.UIAxes7,'ln(I/V^2)','fontsize',18)
            set(gca,'FontSize',18);
            hold(app.UIAxes7,"off");
        end

        % Button pushed function: selectfolderButton
        function selectfolderButtonPushed(app, event)
            selpath=uigetdir('*.*','choose a folder');
            app.filepathEditField_2.Value=selpath;
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            filename=strcat(app.filepathEditField_2.Value,'/',app.filenameEditField.Value,'.xlsx');
            xlswrite(filename,{'VOLTAGE'},1,'A1');
            xlswrite(filename,{'CURRENT'},1,'B1');
            xlswrite(filename,{'1/V'},1,'C1');
            xlswrite(filename,{'ln(I/V^2)'},1,'D1');
            xlswrite(filename,app.voltageTVS.Value,1,'A2');
            xlswrite(filename,app.currentTVS.Value,1,'B2');
            xlswrite(filename,1./app.voltageTVS.Value,1,'C2');
            xlswrite(filename,log(abs(app.currentTVS.Value./app.voltageTVS.Value.^2)),1,'D2');
            
            fig = uifigure;
            message = {'success save the data!'};
            uialert(fig,message,'save file success','Icon','success');
        end

        % Button pushed function: loadfileButton_2
        function loadfileButton_2Pushed(app, event)
            [filename,pathname]=uigetfile({'*.xls*','load data'});
            if isequal(filename,0)||isequal(pathname,0)
                errordlg("haven't select a file",'error');
                return;
            end
            file=strcat(pathname,filename);
            app.filepathEditField_3.Value=file;
        end

        % Value changed function: voltagecolumnincapitalEditField_2
        function voltagecolumnincapitalEditField_2ValueChanged(app, event)
            value = app.voltagecolumnincapitalEditField_2.Value;
            app.voltageEditField_2.Value=strcat(app.voltagecolumnincapitalEditField_2.Value,app.lineEditField_3.Value,":",app.voltagecolumnincapitalEditField_2.Value,app.toEditField_3.Value);
            
        end

        % Value changed function: lineEditField_3
        function lineEditField_3ValueChanged(app, event)
            value = app.lineEditField_3.Value;
            app.voltageEditField_2.Value=strcat(app.voltagecolumnincapitalEditField_2.Value,app.lineEditField_3.Value,":",app.voltagecolumnincapitalEditField_2.Value,app.toEditField_3.Value);
            
        end

        % Value changed function: toEditField_3
        function toEditField_3ValueChanged(app, event)
            value = app.toEditField_3.Value;
            app.voltageEditField_2.Value=strcat(app.voltagecolumnincapitalEditField_2.Value,app.lineEditField_3.Value,":",app.voltagecolumnincapitalEditField_2.Value,app.toEditField_3.Value);
            
        end

        % Value changed function: currentcolumnincapitalEditField_2
        function currentcolumnincapitalEditField_2ValueChanged(app, event)
            value = app.currentcolumnincapitalEditField_2.Value;
            app.currentEditField_2.Value=strcat(app.currentcolumnincapitalEditField_2.Value,app.lineEditField_4.Value,":",app.currentcolumnincapitalEditField_2.Value,app.toEditField_4.Value);
            
        end

        % Value changed function: lineEditField_4
        function lineEditField_4ValueChanged(app, event)
            value = app.lineEditField_4.Value;
            app.currentEditField_2.Value=strcat(app.currentcolumnincapitalEditField_2.Value,app.lineEditField_4.Value,":",app.currentcolumnincapitalEditField_2.Value,app.toEditField_4.Value);
            
        end

        % Value changed function: toEditField_4
        function toEditField_4ValueChanged(app, event)
            value = app.toEditField_4.Value;
            app.currentEditField_2.Value=strcat(app.currentcolumnincapitalEditField_2.Value,app.lineEditField_4.Value,":",app.currentcolumnincapitalEditField_2.Value,app.toEditField_4.Value);
            
        end

        % Button pushed function: plotButton_2
        function plotButton_2Pushed(app, event)
            app.UIAxes6_2.cla;
            app.UIAxes7_2.cla;
            
            app.voltageNDC2.Value(:)=0;
            app.currentNDC2.Value(:)=0;
            app.currentdiffNDC.Value(:)=0;
            app.voltageNDC.Value=xlsread(app.filepathEditField_3.Value,app.sheetEditField_2.Value,app.voltageEditField_2.Value);
            app.currentNDC.Value=xlsread(app.filepathEditField_3.Value,app.sheetEditField_2.Value,app.currentEditField_2.Value);
            
            dV=app.voltageNDC.Value(2)-app.voltageNDC.Value(1);
            
            
            for i=1:length(app.voltageNDC.Value)-3
                app.voltageNDC2.Value(i)=app.voltageNDC.Value(i)+2.5*dV;
                app.currentdiffNDC.Value(i)=(app.currentNDC.Value(i+2)+app.currentNDC.Value(i+3)-app.currentNDC.Value(i)-app.currentNDC.Value(i+1))/(4*dV);
                app.currentNDC2.Value(i)=(app.currentNDC.Value(i+2)+app.currentNDC.Value(i+3)+app.currentNDC.Value(i)+app.currentNDC.Value(i+1))/4;
                
            end
            %
            plot(app.UIAxes6_2,app.voltageNDC2.Value(1:i),app.currentNDC2.Value(1:i),'Color',[11/255 7/255 137/255],'LineWidth',3);
            hold(app.UIAxes6_2,"on");
            xlabel(app.UIAxes6_2,'Voltage/V','fontsize',18)
            ylabel(app.UIAxes6_2,'\itI/','fontsize',18)
            set(gca,'FontSize',18);
            hold(app.UIAxes6_2,"off");
            
            plot(app.UIAxes7_2,app.voltageNDC2.Value(1:i),app.currentdiffNDC.Value(1:i).*app.voltageNDC2.Value(1:i)./app.currentNDC2.Value(1:i),'Color',[11/255 7/255 137/255],'LineWidth',3);
            hold(app.UIAxes7_2,"on");
            line(app.UIAxes7_2,[app.voltageNDC2.Value(1),app.voltageNDC2.Value(length(app.voltageNDC2.Value))],[2,2],'linestyle','--')
            xlabel(app.UIAxes7_2,'Voltage/V','fontsize',18)
            ylabel(app.UIAxes7_2,'(dI/dV)V/I','fontsize',18)
            set(gca,'FontSize',18);
            hold(app.UIAxes7_2,"off");
        end

        % Button pushed function: selectfolderButton_2
        function selectfolderButton_2Pushed(app, event)
            selpath=uigetdir('*.*','choose a folder');
            app.filepathEditField_4.Value=selpath;
        end

        % Button pushed function: saveButton_2
        function saveButton_2Pushed(app, event)
            filename=strcat(app.filepathEditField_4.Value,'/',app.filenameEditField_2.Value,'.xlsx');
            xlswrite(filename,{'VOLTAGE'},1,'A1');
            xlswrite(filename,{'CURRENT'},1,'B1');
            xlswrite(filename,{'(dI/dV)V/I'},1,'C1');
            %             xlswrite(filename,{'ln(I/V^2)'},1,'D1');
            xlswrite(filename,app.voltageNDC2.Value',1,'A2');
            xlswrite(filename,app.currentNDC2.Value',1,'B2');
            xlswrite(filename,(app.currentdiffNDC.Value.*app.voltageNDC2.Value./app.currentNDC2.Value)',1,'C2');
            % %             xlswrite(filename,log(abs(app.currentTVS.Value./app.voltageTVS.Value.^2)),1,'D2');
            
            fig = uifigure;
            message = {'success save the data!'};
            uialert(fig,message,'save file success','Icon','success');
        end

        % Button pushed function: homeButton
        function homeButtonPushed(app, event)
            delete(app.UIFigure);
            run COVERV2.mlapp;
        end

        % Image clicked function: Image5
        function Image5Clicked(app, event)
            delete(app.UIFigure);
            run COVERV2.mlapp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.TVSTab = uitab(app.TabGroup2);
            app.TVSTab.Title = 'TVS';
            app.selectdataPanel = uipanel(app.TVSTab);
            app.selectdataPanel.Title = 'select data';
            app.loadfileButton = uibutton(app.selectdataPanel, 'push');
            app.loadfileButton.ButtonPushedFcn = createCallbackFcn(app, @loadfileButtonPushed, true);
            app.loadfileButton.Text = 'load file';
            app.filepathEditFieldLabel = uilabel(app.selectdataPanel);
            app.filepathEditFieldLabel.Text = 'file path';
            app.filepathEditField = uieditfield(app.selectdataPanel, 'text');
            app.filepathEditField.Value = '0';
            app.sheetEditFieldLabel = uilabel(app.selectdataPanel);
            app.sheetEditFieldLabel.Text = 'sheet';
            app.sheetEditField = uieditfield(app.selectdataPanel, 'numeric');
            app.sheetEditField.Value = 1;
            app.Panel = uipanel(app.TVSTab);
            app.lineEditFieldLabel = uilabel(app.Panel);
            app.lineEditFieldLabel.Text = 'line';
            app.lineEditField = uieditfield(app.Panel, 'text');
            app.lineEditField.ValueChangedFcn = createCallbackFcn(app, @lineEditFieldValueChanged, true);
            app.lineEditField.Value = '1';
            app.lineEditField_2Label = uilabel(app.Panel);
            app.lineEditField_2Label.Text = 'line';
            app.lineEditField_2 = uieditfield(app.Panel, 'text');
            app.lineEditField_2.ValueChangedFcn = createCallbackFcn(app, @lineEditField_2ValueChanged, true);
            app.lineEditField_2.Value = '1';
            app.toEditFieldLabel = uilabel(app.Panel);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.Panel, 'text');
            app.toEditField.ValueChangedFcn = createCallbackFcn(app, @toEditFieldValueChanged, true);
            app.toEditField.Value = '120';
            app.toEditField_2Label = uilabel(app.Panel);
            app.toEditField_2Label.Text = 'to';
            app.toEditField_2 = uieditfield(app.Panel, 'text');
            app.toEditField_2.ValueChangedFcn = createCallbackFcn(app, @toEditField_2ValueChanged, true);
            app.toEditField_2.Value = '120';
            app.voltagecolumnincapitalEditFieldLabel = uilabel(app.Panel);
            app.voltagecolumnincapitalEditFieldLabel.Text = 'voltage column(in capital)';
            app.voltagecolumnincapitalEditField = uieditfield(app.Panel, 'text');
            app.voltagecolumnincapitalEditField.ValueChangedFcn = createCallbackFcn(app, @voltagecolumnincapitalEditFieldValueChanged, true);
            app.voltagecolumnincapitalEditField.Value = 'A';
            app.currentcolumnincapitalEditFieldLabel = uilabel(app.Panel);
            app.currentcolumnincapitalEditFieldLabel.Text = 'current column(in capital)';
            app.currentcolumnincapitalEditField = uieditfield(app.Panel, 'text');
            app.currentcolumnincapitalEditField.ValueChangedFcn = createCallbackFcn(app, @currentcolumnincapitalEditFieldValueChanged, true);
            app.currentcolumnincapitalEditField.Value = 'B';
            app.voltageEditFieldLabel = uilabel(app.Panel);
            app.voltageEditFieldLabel.Text = 'voltage';
            app.voltageEditField = uieditfield(app.Panel, 'text');
            app.voltageEditField.Value = 'A1:A120';
            app.currentEditFieldLabel = uilabel(app.Panel);
            app.currentEditFieldLabel.Text = 'current';
            app.currentEditField = uieditfield(app.Panel, 'text');
            app.currentEditField.Value = 'B1:B120';
            app.UIAxes6 = uiaxes(app.TVSTab);
            title(app.UIAxes6, 'data')
            xlabel(app.UIAxes6, 'voltage')
            ylabel(app.UIAxes6, 'current')
            app.plotButton = uibutton(app.TVSTab, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.UIAxes7 = uiaxes(app.TVSTab);
            title(app.UIAxes7, 'TVS')
            xlabel(app.UIAxes7, '1/V')
            ylabel(app.UIAxes7, 'ln(I/V^2)')
            app.savedataPanel = uipanel(app.TVSTab);
            app.savedataPanel.Title = 'save data';
            app.selectfolderButton = uibutton(app.savedataPanel, 'push');
            app.selectfolderButton.ButtonPushedFcn = createCallbackFcn(app, @selectfolderButtonPushed, true);
            app.selectfolderButton.Text = 'select folder';
            app.filepathEditField_2Label = uilabel(app.savedataPanel);
            app.filepathEditField_2Label.Text = 'file path';
            app.filepathEditField_2 = uieditfield(app.savedataPanel, 'text');
            app.filepathEditField_2.Value = '0';
            app.filenameEditFieldLabel = uilabel(app.savedataPanel);
            app.filenameEditFieldLabel.Text = 'filename';
            app.filenameEditField = uieditfield(app.savedataPanel, 'text');
            app.filenameEditField.Value = '1';
            app.saveButton = uibutton(app.savedataPanel, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.transitionvoltageisEditFieldLabel = uilabel(app.TVSTab);
            app.transitionvoltageisEditFieldLabel.Text = 'transition voltage is';
            app.transitionvoltageisEditField = uieditfield(app.TVSTab, 'numeric');
            app.andEditFieldLabel = uilabel(app.TVSTab);
            app.andEditFieldLabel.Text = 'and';
            app.andEditField = uieditfield(app.TVSTab, 'numeric');
            app.NDCTab = uitab(app.TabGroup2);
            app.NDCTab.Title = 'NDC';
            app.selectdataPanel_2 = uipanel(app.NDCTab);
            app.selectdataPanel_2.Title = 'select data';
            app.loadfileButton_2 = uibutton(app.selectdataPanel_2, 'push');
            app.loadfileButton_2.ButtonPushedFcn = createCallbackFcn(app, @loadfileButton_2Pushed, true);
            app.loadfileButton_2.Text = 'load file';
            app.filepathEditField_3Label = uilabel(app.selectdataPanel_2);
            app.filepathEditField_3Label.Text = 'file path';
            app.filepathEditField_3 = uieditfield(app.selectdataPanel_2, 'text');
            app.filepathEditField_3.Value = '0';
            app.sheetEditField_2Label = uilabel(app.selectdataPanel_2);
            app.sheetEditField_2Label.Text = 'sheet';
            app.sheetEditField_2 = uieditfield(app.selectdataPanel_2, 'numeric');
            app.sheetEditField_2.Value = 1;
            app.Panel_2 = uipanel(app.NDCTab);
            app.lineEditField_3Label = uilabel(app.Panel_2);
            app.lineEditField_3Label.Text = 'line';
            app.lineEditField_3 = uieditfield(app.Panel_2, 'text');
            app.lineEditField_3.ValueChangedFcn = createCallbackFcn(app, @lineEditField_3ValueChanged, true);
            app.lineEditField_3.Value = '1';
            app.lineEditField_4Label = uilabel(app.Panel_2);
            app.lineEditField_4Label.Text = 'line';
            app.lineEditField_4 = uieditfield(app.Panel_2, 'text');
            app.lineEditField_4.ValueChangedFcn = createCallbackFcn(app, @lineEditField_4ValueChanged, true);
            app.lineEditField_4.Value = '1';
            app.toEditField_3Label = uilabel(app.Panel_2);
            app.toEditField_3Label.Text = 'to';
            app.toEditField_3 = uieditfield(app.Panel_2, 'text');
            app.toEditField_3.ValueChangedFcn = createCallbackFcn(app, @toEditField_3ValueChanged, true);
            app.toEditField_3.Value = '120';
            app.toEditField_4Label = uilabel(app.Panel_2);
            app.toEditField_4Label.Text = 'to';
            app.toEditField_4 = uieditfield(app.Panel_2, 'text');
            app.toEditField_4.ValueChangedFcn = createCallbackFcn(app, @toEditField_4ValueChanged, true);
            app.toEditField_4.Value = '120';
            app.voltagecolumnincapitalEditField_2Label = uilabel(app.Panel_2);
            app.voltagecolumnincapitalEditField_2Label.Text = 'voltage column(in capital)';
            app.voltagecolumnincapitalEditField_2 = uieditfield(app.Panel_2, 'text');
            app.voltagecolumnincapitalEditField_2.ValueChangedFcn = createCallbackFcn(app, @voltagecolumnincapitalEditField_2ValueChanged, true);
            app.voltagecolumnincapitalEditField_2.Value = 'A';
            app.currentcolumnincapitalEditField_2Label = uilabel(app.Panel_2);
            app.currentcolumnincapitalEditField_2Label.Text = 'current column(in capital)';
            app.currentcolumnincapitalEditField_2 = uieditfield(app.Panel_2, 'text');
            app.currentcolumnincapitalEditField_2.ValueChangedFcn = createCallbackFcn(app, @currentcolumnincapitalEditField_2ValueChanged, true);
            app.currentcolumnincapitalEditField_2.Value = 'B';
            app.voltageEditField_2Label = uilabel(app.Panel_2);
            app.voltageEditField_2Label.Text = 'voltage';
            app.voltageEditField_2 = uieditfield(app.Panel_2, 'text');
            app.voltageEditField_2.Value = 'A1:A120';
            app.currentEditField_2Label = uilabel(app.Panel_2);
            app.currentEditField_2Label.Text = 'current';
            app.currentEditField_2 = uieditfield(app.Panel_2, 'text');
            app.currentEditField_2.Value = 'B1:B120';
            app.UIAxes6_2 = uiaxes(app.NDCTab);
            title(app.UIAxes6_2, 'data')
            xlabel(app.UIAxes6_2, 'voltage')
            ylabel(app.UIAxes6_2, 'current')
            app.plotButton_2 = uibutton(app.NDCTab, 'push');
            app.plotButton_2.ButtonPushedFcn = createCallbackFcn(app, @plotButton_2Pushed, true);
            app.plotButton_2.Text = 'plot';
            app.savedataPanel_2 = uipanel(app.NDCTab);
            app.savedataPanel_2.Title = 'save data';
            app.selectfolderButton_2 = uibutton(app.savedataPanel_2, 'push');
            app.selectfolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @selectfolderButton_2Pushed, true);
            app.selectfolderButton_2.Text = 'select folder';
            app.filepathEditField_4Label = uilabel(app.savedataPanel_2);
            app.filepathEditField_4Label.Text = 'file path';
            app.filepathEditField_4 = uieditfield(app.savedataPanel_2, 'text');
            app.filepathEditField_4.Value = '0';
            app.filenameEditField_2Label = uilabel(app.savedataPanel_2);
            app.filenameEditField_2Label.Text = 'filename';
            app.filenameEditField_2 = uieditfield(app.savedataPanel_2, 'text');
            app.filenameEditField_2.Value = '1';
            app.saveButton_2 = uibutton(app.savedataPanel_2, 'push');
            app.saveButton_2.ButtonPushedFcn = createCallbackFcn(app, @saveButton_2Pushed, true);
            app.saveButton_2.Text = 'save';
            app.UIAxes7_2 = uiaxes(app.NDCTab);
            title(app.UIAxes7_2, 'NDC')
            xlabel(app.UIAxes7_2, '1/V')
            ylabel(app.UIAxes7_2, '(dI/dV)V/I')
            app.homeButton = uibutton(app.UIFigure, 'push');
            app.homeButton.ButtonPushedFcn = createCallbackFcn(app, @homeButtonPushed, true);
            app.homeButton.Text = 'home';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'logo_1.png';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Image5Clicked, true);
            app.Image5.ImageSource = 'OIP-C.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dataprocessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end