classdef fitting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        tunnelingTab                    matlab.ui.container.Tab
        selectdataPanel                 matlab.ui.container.Panel
        loadfileButton                  matlab.ui.control.Button
        filepathEditFieldLabel          matlab.ui.control.Label
        filepathEditField               matlab.ui.control.EditField
        sheetEditFieldLabel             matlab.ui.control.Label
        sheetEditField                  matlab.ui.control.NumericEditField
        Panel                           matlab.ui.container.Panel
        lineEditFieldLabel              matlab.ui.control.Label
        lineEditField                   matlab.ui.control.EditField
        lineEditField_2Label            matlab.ui.control.Label
        lineEditField_2                 matlab.ui.control.EditField
        toEditField_2Label              matlab.ui.control.Label
        toEditField_2                   matlab.ui.control.EditField
        toEditField_3Label              matlab.ui.control.Label
        toEditField_3                   matlab.ui.control.EditField
        voltagecolumnincapitalEditFieldLabel  matlab.ui.control.Label
        voltagecolumnincapitalEditField  matlab.ui.control.EditField
        currentcolumnincapitalEditFieldLabel  matlab.ui.control.Label
        currentcolumnincapitalEditField  matlab.ui.control.EditField
        voltageEditFieldLabel           matlab.ui.control.Label
        voltageEditField                matlab.ui.control.EditField
        currentEditFieldLabel           matlab.ui.control.Label
        currentEditField                matlab.ui.control.EditField
        fitButton                       matlab.ui.control.Button
        savedataPanel                   matlab.ui.container.Panel
        selectfolderButton              matlab.ui.control.Button
        filepathEditField_2Label        matlab.ui.control.Label
        filepathEditField_2             matlab.ui.control.EditField
        filenameEditFieldLabel          matlab.ui.control.Label
        filenameEditField               matlab.ui.control.EditField
        saveButton                      matlab.ui.control.Button
        tunnelingparametersPanel        matlab.ui.container.Panel
        egEditFieldLabel                matlab.ui.control.Label
        egEditField                     matlab.ui.control.NumericEditField
        grEditFieldLabel                matlab.ui.control.Label
        grEditField                     matlab.ui.control.NumericEditField
        glEditFieldLabel                matlab.ui.control.Label
        glEditField                     matlab.ui.control.NumericEditField
        alphaEditFieldLabel             matlab.ui.control.Label
        alphaEditField                  matlab.ui.control.NumericEditField
        fittingplotVfromEditFieldLabel  matlab.ui.control.Label
        fittingplotVfromEditField       matlab.ui.control.NumericEditField
        toEditFieldLabel                matlab.ui.control.Label
        toEditField                     matlab.ui.control.NumericEditField
        UIAxes6_3                       matlab.ui.control.UIAxes
        UIAxes7_3                       matlab.ui.control.UIAxes
        fittingmethodDropDown_3Label    matlab.ui.control.Label
        fittingmethodDropDown           matlab.ui.control.DropDown
        hoppingTab                      matlab.ui.container.Tab
        selectdataPanel_2               matlab.ui.container.Panel
        loadfileButton_2                matlab.ui.control.Button
        filepathEditField_3Label        matlab.ui.control.Label
        filepathEditField_3             matlab.ui.control.EditField
        sheetEditField_2Label           matlab.ui.control.Label
        sheetEditField_2                matlab.ui.control.NumericEditField
        Panel_4                         matlab.ui.container.Panel
        lineEditField_3Label            matlab.ui.control.Label
        lineEditField_3                 matlab.ui.control.EditField
        lineEditField_4Label            matlab.ui.control.Label
        lineEditField_4                 matlab.ui.control.EditField
        toEditField_4Label              matlab.ui.control.Label
        toEditField_4                   matlab.ui.control.EditField
        toEditField_5Label              matlab.ui.control.Label
        toEditField_5                   matlab.ui.control.EditField
        voltagecolumnincapitalEditField_2Label  matlab.ui.control.Label
        voltagecolumnincapitalEditField_2  matlab.ui.control.EditField
        currentcolumnincapitalEditField_2Label  matlab.ui.control.Label
        currentcolumnincapitalEditField_2  matlab.ui.control.EditField
        voltageEditField_2Label         matlab.ui.control.Label
        voltageEditField_2              matlab.ui.control.EditField
        currentEditField_2Label         matlab.ui.control.Label
        currentEditField_2              matlab.ui.control.EditField
        fitButton_2                     matlab.ui.control.Button
        savedataPanel_2                 matlab.ui.container.Panel
        selectfolderButton_2            matlab.ui.control.Button
        filepathEditField_4Label        matlab.ui.control.Label
        filepathEditField_4             matlab.ui.control.EditField
        filenameEditField_2Label        matlab.ui.control.Label
        filenameEditField_2             matlab.ui.control.EditField
        saveButton_2                    matlab.ui.control.Button
        hoppingparametersPanel          matlab.ui.container.Panel
        egEditField_2Label              matlab.ui.control.Label
        egEditField_2                   matlab.ui.control.NumericEditField
        grEditField_2Label              matlab.ui.control.Label
        grEditField_2                   matlab.ui.control.NumericEditField
        glEditField_2Label              matlab.ui.control.Label
        glEditField_2                   matlab.ui.control.NumericEditField
        alphaEditField_2Label           matlab.ui.control.Label
        alphaEditField_2                matlab.ui.control.NumericEditField
        fittingplotVfromEditField_2Label  matlab.ui.control.Label
        fittingplotVfromEditField_2     matlab.ui.control.NumericEditField
        toEditField_6Label              matlab.ui.control.Label
        toEditField_6                   matlab.ui.control.NumericEditField
        lambdaEditFieldLabel            matlab.ui.control.Label
        lambdaEditField                 matlab.ui.control.NumericEditField
        grEditField_2Label_2            matlab.ui.control.Label
        grEditField_2Label_3            matlab.ui.control.Label
        UIAxes6_4                       matlab.ui.control.UIAxes
        UIAxes7_4                       matlab.ui.control.UIAxes
        fittingmethodDropDown_3Label_2  matlab.ui.control.Label
        fittingmethodDropDown_2         matlab.ui.control.DropDown
        homeButton                      matlab.ui.control.Button
        Image3                          matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
    end

    
    properties (Access = private)
        voltagefittun;
        currentfittun;
        voltagefithop;
        currentfithop;
        Vfittun;
        Ifittun;
        Vfithop;
        Ifithop;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function returnButtonPushed(app, event)
            delete(app.UIFigure);
            run coverr.mlapp;
        end

        % Size changed function: tunnelingTab
        function tunnelingTabSizeChanged(app, event)
            
        end

        % Image clicked function: Image5
        function Image5Clicked(app, event)
            delete(app.UIFigure);
            run COVERV2.mlapp;
        end

        % Button pushed function: loadfileButton
        function loadfileButtonPushed(app, event)
            [filename,pathname]=uigetfile({'*.xls*','load data'});
            if isequal(filename,0)||isequal(pathname,0)
                errordlg("haven't select a file",'error');
                return;
            end
            file=strcat(pathname,filename);
            app.filepathEditField.Value=file;
        end

        % Value changed function: voltagecolumnincapitalEditField
        function voltagecolumnincapitalEditFieldValueChanged(app, event)
            value = app.voltagecolumnincapitalEditField.Value;
            app.voltageEditField.Value=strcat(app.voltagecolumnincapitalEditField.Value,app.lineEditField.Value,":",app.voltagecolumnincapitalEditField.Value,app.toEditField_2.Value);
            
        end

        % Value changed function: lineEditField
        function lineEditFieldValueChanged(app, event)
            value = app.lineEditField.Value;
            app.voltageEditField.Value=strcat(app.voltagecolumnincapitalEditField.Value,app.lineEditField.Value,":",app.voltagecolumnincapitalEditField.Value,app.toEditField_2.Value);
            
        end

        % Value changed function: toEditField_2
        function toEditField_2ValueChanged(app, event)
            value = app.toEditField_2.Value;
            app.voltageEditField.Value=strcat(app.voltagecolumnincapitalEditField.Value,app.lineEditField.Value,":",app.voltagecolumnincapitalEditField.Value,app.toEditField_2.Value);
            
        end

        % Value changed function: currentcolumnincapitalEditField
        function currentcolumnincapitalEditFieldValueChanged(app, event)
            value = app.currentcolumnincapitalEditField.Value;
            app.currentEditField.Value=strcat(app.currentcolumnincapitalEditField.Value,app.lineEditField_2.Value,":",app.currentcolumnincapitalEditField.Value,app.toEditField_3.Value);
            
        end

        % Value changed function: lineEditField_2
        function lineEditField_2ValueChanged(app, event)
            value = app.lineEditField_2.Value;
            app.currentEditField.Value=strcat(app.currentcolumnincapitalEditField.Value,app.lineEditField_2.Value,":",app.currentcolumnincapitalEditField.Value,app.toEditField_3.Value);
            
        end

        % Value changed function: toEditField_3
        function toEditField_3ValueChanged(app, event)
            value = app.toEditField_3.Value;
            app.currentEditField.Value=strcat(app.currentcolumnincapitalEditField.Value,app.lineEditField_2.Value,":",app.currentcolumnincapitalEditField.Value,app.toEditField_3.Value);
            
        end

        % Button pushed function: fitButton
        function fitButtonPushed(app, event)
            app.UIAxes6_3.cla;
            app.UIAxes7_3.cla;
            
            app.voltagefittun.Value=xlsread(app.filepathEditField.Value,app.sheetEditField.Value,app.voltageEditField.Value);
            app.currentfittun.Value=xlsread(app.filepathEditField.Value,app.sheetEditField.Value,app.currentEditField.Value);
            eg=app.egEditField.Value;
            gr=app.grEditField.Value;
            gl=app.glEditField.Value;
            alpha=app.alphaEditField.Value;


            IT=app.currentfittun.Value';
            VT2=app.voltagefittun.Value';
            
            
            if app.fittingmethodDropDown.Value=='LMnonlinear'
                betaT0=[eg gr gl alpha];    %势垒  左右耦合强度
                opts = statset('RobustWgtFun','bisquare','MaxIter',1000);
                %opts.RobustWgtFun = 'bisquare';
                betaT=nlinfit(VT2,IT,'TunnelingR',betaT0,opts);
            elseif app.fittingmethodDropDown.Value=='leastsquare'
                betaT0=[eg gr gl alpha];    %势垒  左右耦合强度
                fun=@(betaT0)TunnelingR(betaT0,VT2)-IT;
                OP3 = optimoptions(@lsqnonlin,'Algorithm','trust-region-reflective','MaxFunctionEvaluations',1e4,'MaxIterations',1e3);
                betaT = lsqnonlin(fun,betaT0,[0,0,0,0],[5,1,1,1],OP3);%parameter scale
            end
            EgT=betaT(1);         %拟合的参数结果，势垒
            GamaR=betaT(2);       %拟合的参数结果。耦合强度
            GamaL=betaT(3);
            c=betaT(4);
            betaT1=[EgT GamaR GamaL c];
            app.Vfittun.Value=linspace(app.fittingplotVfromEditField.Value,app.toEditField.Value,501);
            app.Ifittun.Value=TunnelingR(betaT1,app.Vfittun.Value);
            
            
            plot(app.UIAxes6_3,app.Vfittun.Value,log10(abs(app.Ifittun.Value)),'Color',[11/255 7/255 137/255],'LineWidth',3);
            hold(app.UIAxes6_3,"on");
            plot(app.UIAxes6_3,app.voltagefittun.Value,log10(abs(app.currentfittun.Value)),'r.','Color','r','LineWidth',3);
            xlabel(app.UIAxes6_3,'Voltage/V','fontsize',18)
            ylabel(app.UIAxes6_3,'log10(I)','fontsize',18)
            set(gca,'FontSize',18);
            hold(app.UIAxes6_3,"off");
            legend(app.UIAxes6_3,{'fit','data'},'FontSize',6);
            
            plot(app.UIAxes7_3,app.Vfittun.Value,app.Ifittun.Value,'Color',[11/255 7/255 137/255],'LineWidth',3);
            hold(app.UIAxes7_3,"on");
            plot(app.UIAxes7_3,app.voltagefittun.Value,app.currentfittun.Value,'r.','Color','r','LineWidth',3);
            xlabel(app.UIAxes7_3,'Voltage/V','fontsize',18)
            ylabel(app.UIAxes7_3,'current/A','fontsize',18)
            set(gca,'FontSize',18);
            hold(app.UIAxes7_3,"off");
            legend(app.UIAxes7_3,{'fit','data'},'FontSize',6);
        end

        % Button pushed function: selectfolderButton
        function selectfolderButtonPushed(app, event)
            selpath=uigetdir('*.*','choose a folder');
            app.filepathEditField_2.Value=selpath;
        end

        % Button pushed function: fitButton_2
        function fitButton_2Pushed(app, event)
            app.UIAxes6_4.cla;
            app.UIAxes7_4.cla;
            
            app.voltagefithop.Value=xlsread(app.filepathEditField_3.Value,app.sheetEditField_2.Value,app.voltageEditField_2.Value);
            app.currentfithop.Value=xlsread(app.filepathEditField_3.Value,app.sheetEditField_2.Value,app.currentEditField_2.Value);
            eg=app.egEditField_2.Value;
            gr=app.grEditField_2.Value*10^13;
            gl=app.glEditField_2.Value*10^13;
            alpha=app.alphaEditField_2.Value;
            lambda=app.lambdaEditField.Value;


            IH=app.currentfithop.Value';
            VH=app.voltagefithop.Value';
            if app.fittingmethodDropDown_2.Value=='LMnonlinear'
                betah0=[eg lambda gr gl alpha];      %势垒  重组能 左右耦合强度  分压因子
                % betah0=[0.06 0.9 10^10 10^11 0.3];      %势垒  重组能 左右耦合强度  分压因子
                opts = statset('RobustWgtFun','bisquare','MaxIter',1000);
                %'bisquare', 'andrews', 'cauchy', 'fair', 'huber','logistic', 'talwar', or 'welsch'.
                %opts.RobustWgtFun = 'bisquare';
                %opts.MaxIter=1000;
                %options.MaxIter = 1;
                betah=nlinfit(VH,IH,'HoppingR',betah0,opts);
            elseif app.fittingmethodDropDown_2.Value=='leastsquare'
                betaT0=[eg lambda gr gl alpha];    %势垒  左右耦合强度
                fun=@(betaT0)HoppingR(betaT0,VH)-IH;
                OP3 = optimoptions(@lsqnonlin,'Algorithm','trust-region-reflective','MaxFunctionEvaluations',1e4,'MaxIterations',1e3);
                betah = lsqnonlin(fun,betaT0,[0,0,0,0,0],[5,5,1,1,1],OP3);%parameter scale
            end
            EgH=betah(1);
            lambdag=betah(2);
            gamar=betah(3);
            gamal=betah(4);
            cg=betah(5);
            betah1=[EgH lambdag gamar gamal cg];
            %             VV=linspace(-1,1,500);
            %             IHfit=HoppingR(betah1,VV);
            
            
            app.Vfithop.Value=linspace(app.fittingplotVfromEditField_2.Value,app.toEditField_6.Value,501);
            app.Ifithop.Value=HoppingR(betah1,app.Vfithop.Value);
            
            
            plot(app.UIAxes6_4,app.Vfithop.Value,log10(abs(app.Ifithop.Value)),'Color',[11/255 7/255 137/255],'LineWidth',3);
            hold(app.UIAxes6_4,"on");
            plot(app.UIAxes6_4,app.voltagefithop.Value,log10(abs(app.currentfithop.Value)),'r.','Color','r','LineWidth',3);
            xlabel(app.UIAxes6_4,'Voltage/V','fontsize',18)
            ylabel(app.UIAxes6_4,'log10(I)','fontsize',18)
            set(gca,'FontSize',18);
            hold(app.UIAxes6_4,"off");
            legend(app.UIAxes6_4,{'fit','data'},'FontSize',6);


            plot(app.UIAxes7_4,app.Vfithop.Value,app.Ifithop.Value,'Color',[11/255 7/255 137/255],'LineWidth',3);
            hold(app.UIAxes7_4,"on");
            plot(app.UIAxes7_4,app.voltagefithop.Value,app.currentfithop.Value,'r.','Color','r','LineWidth',3);
            xlabel(app.UIAxes7_4,'Voltage/V','fontsize',18)
            ylabel(app.UIAxes7_4,'current/A','fontsize',18)
            set(gca,'FontSize',18);
            hold(app.UIAxes7_4,"off");
            legend(app.UIAxes7_4,{'fit','data'},'FontSize',6);
        end

        % Button pushed function: loadfileButton_2
        function loadfileButton_2Pushed(app, event)
            [filename,pathname]=uigetfile({'*.xls*','load data'});
            if isequal(filename,0)||isequal(pathname,0)
                errordlg("haven't select a file",'error');
                return;
            end
            file=strcat(pathname,filename);
            app.filepathEditField_3.Value=file;
        end

        % Value changed function: voltagecolumnincapitalEditField_2
        function voltagecolumnincapitalEditField_2ValueChanged(app, event)
            app.voltageEditField_2.Value=strcat(app.voltagecolumnincapitalEditField_2.Value,app.lineEditField_3.Value,":",app.voltagecolumnincapitalEditField_2.Value,app.toEditField_4.Value);
            
        end

        % Value changed function: lineEditField_3
        function lineEditField_3ValueChanged(app, event)
            value = app.lineEditField_3.Value;
            app.voltageEditField_2.Value=strcat(app.voltagecolumnincapitalEditField_2.Value,app.lineEditField_3.Value,":",app.voltagecolumnincapitalEditField_2.Value,app.toEditField_4.Value);
            
        end

        % Value changed function: toEditField_4
        function toEditField_4ValueChanged(app, event)
            value = app.toEditField_4.Value;
            app.voltageEditField_2.Value=strcat(app.voltagecolumnincapitalEditField_2.Value,app.lineEditField_3.Value,":",app.voltagecolumnincapitalEditField_2.Value,app.toEditField_4.Value);
            
        end

        % Value changed function: currentcolumnincapitalEditField_2
        function currentcolumnincapitalEditField_2ValueChanged(app, event)
            value = app.currentcolumnincapitalEditField_2.Value;
            app.currentEditField_2.Value=strcat(app.currentcolumnincapitalEditField_2.Value,app.lineEditField_4.Value,":",app.currentcolumnincapitalEditField_2.Value,app.toEditField_5.Value);
            
        end

        % Value changed function: lineEditField_4
        function lineEditField_4ValueChanged(app, event)
            value = app.lineEditField_4.Value;
            app.currentEditField_2.Value=strcat(app.currentcolumnincapitalEditField_2.Value,app.lineEditField_4.Value,":",app.currentcolumnincapitalEditField_2.Value,app.toEditField_5.Value);
            
        end

        % Value changed function: toEditField_5
        function toEditField_5ValueChanged(app, event)
            value = app.toEditField_5.Value;
            app.currentEditField_2.Value=strcat(app.currentcolumnincapitalEditField_2.Value,app.lineEditField_4.Value,":",app.currentcolumnincapitalEditField_2.Value,app.toEditField_5.Value);
            
        end

        % Button pushed function: selectfolderButton_2
        function selectfolderButton_2Pushed(app, event)
            selpath=uigetdir('*.*','choose a folder');
            app.filepathEditField_2.Value=selpath;
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            filename=strcat(app.filepathEditField_2.Value,'/',app.filenameEditField.Value,'.xlsx');
            xlswrite(filename,{'fitVOLTAGE'},1,'A1');
            xlswrite(filename,{'fitCURRENT'},1,'B1');
            xlswrite(filename,{'datavoltage'},1,'C1');
            xlswrite(filename,{'datacurrent'},1,'D1');
            %             xlswrite(filename,{'ln(I/V^2)'},1,'D1');
            xlswrite(filename,app.Vfittun.Value',1,'A2');
            xlswrite(filename,app.Ifittun.Value',1,'B2');
            xlswrite(filename,app.voltagefittun.Value',1,'C2');
            xlswrite(filename,app.currentfittun.Value',1,'D2');
            % %             xlswrite(filename,log(abs(app.currentTVS.Value./app.voltageTVS.Value.^2)),1,'D2');
            
            fig = uifigure;
            message = {'success save the data!'};
            uialert(fig,message,'save file success','Icon','success');
        end

        % Button pushed function: saveButton_2
        function saveButton_2Pushed(app, event)
            filename=strcat(app.filepathEditField_4.Value,'/',app.filenameEditField_2.Value,'.xlsx');
            xlswrite(filename,{'fitVOLTAGE'},1,'A1');
            xlswrite(filename,{'fitCURRENT'},1,'B1');
            xlswrite(filename,{'datavoltage'},1,'C1');
            xlswrite(filename,{'datacurrent'},1,'D1');
            %             xlswrite(filename,{'ln(I/V^2)'},1,'D1');
            xlswrite(filename,app.Vfittun.Value',1,'A2');
            xlswrite(filename,app.Ifittun.Value',1,'B2');
            xlswrite(filename,app.voltagefittun.Value',1,'C2');
            xlswrite(filename,app.currentfittun.Value',1,'D2');
            % %             xlswrite(filename,log(abs(app.currentTVS.Value./app.voltageTVS.Value.^2)),1,'D2');
            
            fig = uifigure;
            message = {'success save the data!'};
            uialert(fig,message,'save file success','Icon','success');
        end

        % Button pushed function: homeButton
        function homeButtonPushed(app, event)
            delete(app.UIFigure);
            run COVERV2.mlapp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.tunnelingTab = uitab(app.TabGroup2);
            app.tunnelingTab.SizeChangedFcn = createCallbackFcn(app, @tunnelingTabSizeChanged, true);
            app.tunnelingTab.Title = 'tunneling';
            app.selectdataPanel = uipanel(app.tunnelingTab);
            app.selectdataPanel.Title = 'select data';
            app.loadfileButton = uibutton(app.selectdataPanel, 'push');
            app.loadfileButton.ButtonPushedFcn = createCallbackFcn(app, @loadfileButtonPushed, true);
            app.loadfileButton.Text = 'load file';
            app.filepathEditFieldLabel = uilabel(app.selectdataPanel);
            app.filepathEditFieldLabel.Text = 'file path';
            app.filepathEditField = uieditfield(app.selectdataPanel, 'text');
            app.filepathEditField.Value = '0';
            app.sheetEditFieldLabel = uilabel(app.selectdataPanel);
            app.sheetEditFieldLabel.Text = 'sheet';
            app.sheetEditField = uieditfield(app.selectdataPanel, 'numeric');
            app.sheetEditField.Value = 1;
            app.Panel = uipanel(app.tunnelingTab);
            app.lineEditFieldLabel = uilabel(app.Panel);
            app.lineEditFieldLabel.Text = 'line';
            app.lineEditField = uieditfield(app.Panel, 'text');
            app.lineEditField.ValueChangedFcn = createCallbackFcn(app, @lineEditFieldValueChanged, true);
            app.lineEditField.Value = '1';
            app.lineEditField_2Label = uilabel(app.Panel);
            app.lineEditField_2Label.Text = 'line';
            app.lineEditField_2 = uieditfield(app.Panel, 'text');
            app.lineEditField_2.ValueChangedFcn = createCallbackFcn(app, @lineEditField_2ValueChanged, true);
            app.lineEditField_2.Value = '1';
            app.toEditField_2Label = uilabel(app.Panel);
            app.toEditField_2Label.Text = 'to';
            app.toEditField_2 = uieditfield(app.Panel, 'text');
            app.toEditField_2.ValueChangedFcn = createCallbackFcn(app, @toEditField_2ValueChanged, true);
            app.toEditField_2.Value = '120';
            app.toEditField_3Label = uilabel(app.Panel);
            app.toEditField_3Label.Text = 'to';
            app.toEditField_3 = uieditfield(app.Panel, 'text');
            app.toEditField_3.ValueChangedFcn = createCallbackFcn(app, @toEditField_3ValueChanged, true);
            app.toEditField_3.Value = '120';
            app.voltagecolumnincapitalEditFieldLabel = uilabel(app.Panel);
            app.voltagecolumnincapitalEditFieldLabel.Text = 'voltage column(in capital)';
            app.voltagecolumnincapitalEditField = uieditfield(app.Panel, 'text');
            app.voltagecolumnincapitalEditField.ValueChangedFcn = createCallbackFcn(app, @voltagecolumnincapitalEditFieldValueChanged, true);
            app.voltagecolumnincapitalEditField.Value = 'A';
            app.currentcolumnincapitalEditFieldLabel = uilabel(app.Panel);
            app.currentcolumnincapitalEditFieldLabel.Text = 'current column(in capital)';
            app.currentcolumnincapitalEditField = uieditfield(app.Panel, 'text');
            app.currentcolumnincapitalEditField.ValueChangedFcn = createCallbackFcn(app, @currentcolumnincapitalEditFieldValueChanged, true);
            app.currentcolumnincapitalEditField.Value = 'B';
            app.voltageEditFieldLabel = uilabel(app.Panel);
            app.voltageEditFieldLabel.Text = 'voltage';
            app.voltageEditField = uieditfield(app.Panel, 'text');
            app.voltageEditField.Value = 'A1:A201';
            app.currentEditFieldLabel = uilabel(app.Panel);
            app.currentEditFieldLabel.Text = 'current';
            app.currentEditField = uieditfield(app.Panel, 'text');
            app.currentEditField.Value = 'B1:B201';
            app.fitButton = uibutton(app.tunnelingTab, 'push');
            app.fitButton.ButtonPushedFcn = createCallbackFcn(app, @fitButtonPushed, true);
            app.fitButton.Text = 'fit';
            app.savedataPanel = uipanel(app.tunnelingTab);
            app.savedataPanel.Title = 'save data';
            app.selectfolderButton = uibutton(app.savedataPanel, 'push');
            app.selectfolderButton.ButtonPushedFcn = createCallbackFcn(app, @selectfolderButtonPushed, true);
            app.selectfolderButton.Text = 'select folder';
            app.filepathEditField_2Label = uilabel(app.savedataPanel);
            app.filepathEditField_2Label.Text = 'file path';
            app.filepathEditField_2 = uieditfield(app.savedataPanel, 'text');
            app.filepathEditField_2.Value = '0';
            app.filenameEditFieldLabel = uilabel(app.savedataPanel);
            app.filenameEditFieldLabel.Text = 'filename';
            app.filenameEditField = uieditfield(app.savedataPanel, 'text');
            app.filenameEditField.Value = '1';
            app.saveButton = uibutton(app.savedataPanel, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.tunnelingparametersPanel = uipanel(app.tunnelingTab);
            app.tunnelingparametersPanel.Title = 'tunneling parameters';
            app.egEditFieldLabel = uilabel(app.tunnelingparametersPanel);
            app.egEditFieldLabel.Text = 'eg';
            app.egEditField = uieditfield(app.tunnelingparametersPanel, 'numeric');
            app.egEditField.Value = 0.8;
            app.grEditFieldLabel = uilabel(app.tunnelingparametersPanel);
            app.grEditFieldLabel.Text = 'gr';
            app.grEditField = uieditfield(app.tunnelingparametersPanel, 'numeric');
            app.grEditField.Value = 0.01;
            app.glEditFieldLabel = uilabel(app.tunnelingparametersPanel);
            app.glEditFieldLabel.Text = 'gl';
            app.glEditField = uieditfield(app.tunnelingparametersPanel, 'numeric');
            app.glEditField.Value = 0.04;
            app.alphaEditFieldLabel = uilabel(app.tunnelingparametersPanel);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.tunnelingparametersPanel, 'numeric');
            app.alphaEditField.Value = 0.6;
            app.fittingplotVfromEditFieldLabel = uilabel(app.tunnelingparametersPanel);
            app.fittingplotVfromEditFieldLabel.Text = 'fitting plot V from';
            app.fittingplotVfromEditField = uieditfield(app.tunnelingparametersPanel, 'numeric');
            app.fittingplotVfromEditField.Value = -1;
            app.toEditFieldLabel = uilabel(app.tunnelingparametersPanel);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.tunnelingparametersPanel, 'numeric');
            app.toEditField.Value = 1;
            app.UIAxes6_3 = uiaxes(app.tunnelingTab);
            title(app.UIAxes6_3, 'data')
            xlabel(app.UIAxes6_3, 'voltage')
            ylabel(app.UIAxes6_3, 'current')
            app.UIAxes7_3 = uiaxes(app.tunnelingTab);
            title(app.UIAxes7_3, 'data')
            xlabel(app.UIAxes7_3, 'voltage')
            ylabel(app.UIAxes7_3, 'current')
            app.fittingmethodDropDown_3Label = uilabel(app.tunnelingTab);
            app.fittingmethodDropDown_3Label.Text = 'fitting method';
            app.fittingmethodDropDown = uidropdown(app.tunnelingTab);
            app.fittingmethodDropDown.Items = {'LMnonlinear', 'leastsquare'};
            app.fittingmethodDropDown.Value = 'leastsquare';
            app.hoppingTab = uitab(app.TabGroup2);
            app.hoppingTab.Title = 'hopping';
            app.selectdataPanel_2 = uipanel(app.hoppingTab);
            app.selectdataPanel_2.Title = 'select data';
            app.loadfileButton_2 = uibutton(app.selectdataPanel_2, 'push');
            app.loadfileButton_2.ButtonPushedFcn = createCallbackFcn(app, @loadfileButton_2Pushed, true);
            app.loadfileButton_2.Text = 'load file';
            app.filepathEditField_3Label = uilabel(app.selectdataPanel_2);
            app.filepathEditField_3Label.Text = 'file path';
            app.filepathEditField_3 = uieditfield(app.selectdataPanel_2, 'text');
            app.filepathEditField_3.Value = '0';
            app.sheetEditField_2Label = uilabel(app.selectdataPanel_2);
            app.sheetEditField_2Label.Text = 'sheet';
            app.sheetEditField_2 = uieditfield(app.selectdataPanel_2, 'numeric');
            app.sheetEditField_2.Value = 1;
            app.Panel_4 = uipanel(app.hoppingTab);
            app.lineEditField_3Label = uilabel(app.Panel_4);
            app.lineEditField_3Label.Text = 'line';
            app.lineEditField_3 = uieditfield(app.Panel_4, 'text');
            app.lineEditField_3.ValueChangedFcn = createCallbackFcn(app, @lineEditField_3ValueChanged, true);
            app.lineEditField_3.Value = '1';
            app.lineEditField_4Label = uilabel(app.Panel_4);
            app.lineEditField_4Label.Text = 'line';
            app.lineEditField_4 = uieditfield(app.Panel_4, 'text');
            app.lineEditField_4.ValueChangedFcn = createCallbackFcn(app, @lineEditField_4ValueChanged, true);
            app.lineEditField_4.Value = '1';
            app.toEditField_4Label = uilabel(app.Panel_4);
            app.toEditField_4Label.Text = 'to';
            app.toEditField_4 = uieditfield(app.Panel_4, 'text');
            app.toEditField_4.ValueChangedFcn = createCallbackFcn(app, @toEditField_4ValueChanged, true);
            app.toEditField_4.Value = '120';
            app.toEditField_5Label = uilabel(app.Panel_4);
            app.toEditField_5Label.Text = 'to';
            app.toEditField_5 = uieditfield(app.Panel_4, 'text');
            app.toEditField_5.ValueChangedFcn = createCallbackFcn(app, @toEditField_5ValueChanged, true);
            app.toEditField_5.Value = '120';
            app.voltagecolumnincapitalEditField_2Label = uilabel(app.Panel_4);
            app.voltagecolumnincapitalEditField_2Label.Text = 'voltage column(in capital)';
            app.voltagecolumnincapitalEditField_2 = uieditfield(app.Panel_4, 'text');
            app.voltagecolumnincapitalEditField_2.ValueChangedFcn = createCallbackFcn(app, @voltagecolumnincapitalEditField_2ValueChanged, true);
            app.voltagecolumnincapitalEditField_2.Value = 'A';
            app.currentcolumnincapitalEditField_2Label = uilabel(app.Panel_4);
            app.currentcolumnincapitalEditField_2Label.Text = 'current column(in capital)';
            app.currentcolumnincapitalEditField_2 = uieditfield(app.Panel_4, 'text');
            app.currentcolumnincapitalEditField_2.ValueChangedFcn = createCallbackFcn(app, @currentcolumnincapitalEditField_2ValueChanged, true);
            app.currentcolumnincapitalEditField_2.Value = 'B';
            app.voltageEditField_2Label = uilabel(app.Panel_4);
            app.voltageEditField_2Label.Text = 'voltage';
            app.voltageEditField_2 = uieditfield(app.Panel_4, 'text');
            app.voltageEditField_2.Value = 'A1:A201';
            app.currentEditField_2Label = uilabel(app.Panel_4);
            app.currentEditField_2Label.Text = 'current';
            app.currentEditField_2 = uieditfield(app.Panel_4, 'text');
            app.currentEditField_2.Value = 'B1:B201';
            app.fitButton_2 = uibutton(app.hoppingTab, 'push');
            app.fitButton_2.ButtonPushedFcn = createCallbackFcn(app, @fitButton_2Pushed, true);
            app.fitButton_2.Text = 'fit';
            app.savedataPanel_2 = uipanel(app.hoppingTab);
            app.savedataPanel_2.Title = 'save data';
            app.selectfolderButton_2 = uibutton(app.savedataPanel_2, 'push');
            app.selectfolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @selectfolderButton_2Pushed, true);
            app.selectfolderButton_2.Text = 'select folder';
            app.filepathEditField_4Label = uilabel(app.savedataPanel_2);
            app.filepathEditField_4Label.Text = 'file path';
            app.filepathEditField_4 = uieditfield(app.savedataPanel_2, 'text');
            app.filepathEditField_4.Value = '0';
            app.filenameEditField_2Label = uilabel(app.savedataPanel_2);
            app.filenameEditField_2Label.Text = 'filename';
            app.filenameEditField_2 = uieditfield(app.savedataPanel_2, 'text');
            app.filenameEditField_2.Value = '1';
            app.saveButton_2 = uibutton(app.savedataPanel_2, 'push');
            app.saveButton_2.ButtonPushedFcn = createCallbackFcn(app, @saveButton_2Pushed, true);
            app.saveButton_2.Text = 'save';
            app.hoppingparametersPanel = uipanel(app.hoppingTab);
            app.hoppingparametersPanel.Title = 'hopping parameters';
            app.egEditField_2Label = uilabel(app.hoppingparametersPanel);
            app.egEditField_2Label.Text = 'eg';
            app.egEditField_2 = uieditfield(app.hoppingparametersPanel, 'numeric');
            app.egEditField_2.Value = 0.8;
            app.grEditField_2Label = uilabel(app.hoppingparametersPanel);
            app.grEditField_2Label.Text = 'gr';
            app.grEditField_2 = uieditfield(app.hoppingparametersPanel, 'numeric');
            app.grEditField_2.Value = 1;
            app.glEditField_2Label = uilabel(app.hoppingparametersPanel);
            app.glEditField_2Label.Text = 'gl';
            app.glEditField_2 = uieditfield(app.hoppingparametersPanel, 'numeric');
            app.glEditField_2.Value = 1;
            app.alphaEditField_2Label = uilabel(app.hoppingparametersPanel);
            app.alphaEditField_2Label.Text = 'alpha';
            app.alphaEditField_2 = uieditfield(app.hoppingparametersPanel, 'numeric');
            app.alphaEditField_2.Value = 0.6;
            app.fittingplotVfromEditField_2Label = uilabel(app.hoppingparametersPanel);
            app.fittingplotVfromEditField_2Label.Text = 'fitting plot V from';
            app.fittingplotVfromEditField_2 = uieditfield(app.hoppingparametersPanel, 'numeric');
            app.fittingplotVfromEditField_2.Value = -1;
            app.toEditField_6Label = uilabel(app.hoppingparametersPanel);
            app.toEditField_6Label.Text = 'to';
            app.toEditField_6 = uieditfield(app.hoppingparametersPanel, 'numeric');
            app.toEditField_6.Value = 1;
            app.lambdaEditFieldLabel = uilabel(app.hoppingparametersPanel);
            app.lambdaEditFieldLabel.Text = 'lambda';
            app.lambdaEditField = uieditfield(app.hoppingparametersPanel, 'numeric');
            app.lambdaEditField.Value = 0.6;
            app.grEditField_2Label_2 = uilabel(app.hoppingparametersPanel);
            app.grEditField_2Label_2.Text = '*10^13';
            app.grEditField_2Label_3 = uilabel(app.hoppingparametersPanel);
            app.grEditField_2Label_3.Text = '*10^13';
            app.UIAxes6_4 = uiaxes(app.hoppingTab);
            title(app.UIAxes6_4, 'data')
            xlabel(app.UIAxes6_4, 'voltage')
            ylabel(app.UIAxes6_4, 'current')
            app.UIAxes7_4 = uiaxes(app.hoppingTab);
            title(app.UIAxes7_4, 'data')
            xlabel(app.UIAxes7_4, 'voltage')
            ylabel(app.UIAxes7_4, 'current')
            app.fittingmethodDropDown_3Label_2 = uilabel(app.hoppingTab);
            app.fittingmethodDropDown_3Label_2.Text = 'fitting method';
            app.fittingmethodDropDown_2 = uidropdown(app.hoppingTab);
            app.fittingmethodDropDown_2.Items = {'LMnonlinear', 'leastsquare'};
            app.fittingmethodDropDown_2.Value = 'LMnonlinear';
            app.homeButton = uibutton(app.UIFigure, 'push');
            app.homeButton.ButtonPushedFcn = createCallbackFcn(app, @homeButtonPushed, true);
            app.homeButton.Text = 'home';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'logo_1.png';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Image5Clicked, true);
            app.Image5.ImageSource = 'OIP-C.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fitting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end