classdef simulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        tunnelingTab                    matlab.ui.container.Tab
        tunnelingparametersPanel        matlab.ui.container.Panel
        Knob                            matlab.ui.control.Knob
        Knob_2                          matlab.ui.control.Knob
        Knob_3                          matlab.ui.control.Knob
        egLabel                         matlab.ui.control.Label
        egEditField                     matlab.ui.control.Spinner
        grLabel                         matlab.ui.control.Label
        grEditField                     matlab.ui.control.Spinner
        glLabel                         matlab.ui.control.Label
        glEditField                     matlab.ui.control.Spinner
        voltagescalePanel               matlab.ui.container.Panel
        voltagestepEditFieldLabel       matlab.ui.control.Label
        voltagestepEditField            matlab.ui.control.NumericEditField
        Slider                          matlab.ui.control.Slider
        voltagescaleLabel               matlab.ui.control.Label
        voltageEditField                matlab.ui.control.Spinner
        resultPanel                     matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        UIAxeslog                       matlab.ui.control.UIAxes
        rectificationPanel              matlab.ui.container.Panel
        Slider_2                        matlab.ui.control.Slider
        alphaLabel                      matlab.ui.control.Label
        alpha05EditField                matlab.ui.control.Spinner
        SimuButton                      matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        gatevoltagePanel                matlab.ui.container.Panel
        Slider_7                        matlab.ui.control.Slider
        gatevoltageLabel                matlab.ui.control.Label
        voltageEditField_8              matlab.ui.control.Spinner
        gatingefficiencyEditFieldLabel  matlab.ui.control.Label
        gatingefficiencyEditField       matlab.ui.control.NumericEditField
        Image8                          matlab.ui.control.Image
        hoppingTab                      matlab.ui.container.Tab
        hoppingparametersPanel_2        matlab.ui.container.Panel
        grEditField_2Label              matlab.ui.control.Label
        grEditField_2                   matlab.ui.control.NumericEditField
        glEditField_2Label              matlab.ui.control.Label
        glEditField_2                   matlab.ui.control.NumericEditField
        Knob_4                          matlab.ui.control.Knob
        Knob_5                          matlab.ui.control.Knob
        Knob_7                          matlab.ui.control.Knob
        grEditField_2Label_2            matlab.ui.control.Label
        grEditField_2Label_3            matlab.ui.control.Label
        Knob_8                          matlab.ui.control.Knob
        grEditField_2Label_4            matlab.ui.control.Label
        grEditField_2Label_5            matlab.ui.control.Label
        egLabel_2                       matlab.ui.control.Label
        egEditField_2                   matlab.ui.control.Spinner
        lambdaLabel                     matlab.ui.control.Label
        lambdaEditField                 matlab.ui.control.Spinner
        voltagescalePanel_5             matlab.ui.container.Panel
        stepEditField_5Label            matlab.ui.control.Label
        stepEditField_5                 matlab.ui.control.NumericEditField
        Slider_3                        matlab.ui.control.Slider
        voltageLabel_2                  matlab.ui.control.Label
        voltageEditField_5              matlab.ui.control.Spinner
        resultPanel_5                   matlab.ui.container.Panel
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxeslog_5                     matlab.ui.control.UIAxes
        rectificationPanel_3            matlab.ui.container.Panel
        Slider_4                        matlab.ui.control.Slider
        alphaLabel_2                    matlab.ui.control.Label
        alpha05EditField_3              matlab.ui.control.Spinner
        SimuButton_2                    matlab.ui.control.Button
        Image2_5                        matlab.ui.control.Image
        gatevoltagePanel_2              matlab.ui.container.Panel
        Slider_8                        matlab.ui.control.Slider
        gatevoltageLabel_2              matlab.ui.control.Label
        voltageEditField_9              matlab.ui.control.Spinner
        gatingefficiencyEditField_2Label  matlab.ui.control.Label
        gatingefficiencyEditField_2     matlab.ui.control.NumericEditField
        Image8_2                        matlab.ui.control.Image
        mixedtransportTab               matlab.ui.container.Tab
        hoppingchannelPanel             matlab.ui.container.Panel
        grEditField_6Label              matlab.ui.control.Label
        grEditField_6                   matlab.ui.control.NumericEditField
        glEditField_3Label              matlab.ui.control.Label
        glEditField_3                   matlab.ui.control.NumericEditField
        Knob_9                          matlab.ui.control.Knob
        Knob_10                         matlab.ui.control.Knob
        Knob_11                         matlab.ui.control.Knob
        grEditField_2Label_6            matlab.ui.control.Label
        grEditField_2Label_7            matlab.ui.control.Label
        Knob_12                         matlab.ui.control.Knob
        grEditField_2Label_8            matlab.ui.control.Label
        grEditField_2Label_9            matlab.ui.control.Label
        egLabel_3                       matlab.ui.control.Label
        egEditField_3                   matlab.ui.control.Spinner
        lambdaLabel_2                   matlab.ui.control.Label
        lambdaEditField_2               matlab.ui.control.Spinner
        voltagescalePanel_6             matlab.ui.container.Panel
        stepEditField_6Label            matlab.ui.control.Label
        stepEditField_6                 matlab.ui.control.NumericEditField
        Slider_5                        matlab.ui.control.Slider
        voltageLabel_3                  matlab.ui.control.Label
        voltageEditField_6              matlab.ui.control.Spinner
        resultPanel_6                   matlab.ui.container.Panel
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxeslog_6                     matlab.ui.control.UIAxes
        SimuButton_3                    matlab.ui.control.Button
        Image2_6                        matlab.ui.control.Image
        tunnelingchannelPanel           matlab.ui.container.Panel
        Knob_13                         matlab.ui.control.Knob
        Knob_14                         matlab.ui.control.Knob
        Knob_15                         matlab.ui.control.Knob
        egLabel_4                       matlab.ui.control.Label
        egEditField_4                   matlab.ui.control.Spinner
        grLabel_2                       matlab.ui.control.Label
        grEditField_10                  matlab.ui.control.Spinner
        glLabel_2                       matlab.ui.control.Label
        glEditField_4                   matlab.ui.control.Spinner
        nonsteadystateTab               matlab.ui.container.Tab
        resultPanel_7                   matlab.ui.container.Panel
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxeslog_7                     matlab.ui.control.UIAxes
        SimuButton_4                    matlab.ui.control.Button
        Image2_7                        matlab.ui.control.Image
        voltagescalePanel_7             matlab.ui.container.Panel
        stepEditField_7Label            matlab.ui.control.Label
        stepEditField_7                 matlab.ui.control.NumericEditField
        Slider_6                        matlab.ui.control.Slider
        voltageLabel_4                  matlab.ui.control.Label
        voltageEditField_7              matlab.ui.control.Spinner
        channel1Panel                   matlab.ui.container.Panel
        grEditField_11Label             matlab.ui.control.Label
        grEditField_11                  matlab.ui.control.NumericEditField
        glEditField_5Label              matlab.ui.control.Label
        glEditField_5                   matlab.ui.control.NumericEditField
        Knob_16                         matlab.ui.control.Knob
        Knob_17                         matlab.ui.control.Knob
        Knob_18                         matlab.ui.control.Knob
        grEditField_2Label_10           matlab.ui.control.Label
        grEditField_2Label_11           matlab.ui.control.Label
        Knob_19                         matlab.ui.control.Knob
        grEditField_2Label_12           matlab.ui.control.Label
        grEditField_2Label_13           matlab.ui.control.Label
        egLabel_5                       matlab.ui.control.Label
        egEditField_5                   matlab.ui.control.Spinner
        lambdaLabel_3                   matlab.ui.control.Label
        lambdaEditField_3               matlab.ui.control.Spinner
        channel2Panel                   matlab.ui.container.Panel
        grEditField_14Label             matlab.ui.control.Label
        grEditField_14                  matlab.ui.control.NumericEditField
        glEditField_6Label              matlab.ui.control.Label
        glEditField_6                   matlab.ui.control.NumericEditField
        Knob_20                         matlab.ui.control.Knob
        Knob_21                         matlab.ui.control.Knob
        Knob_22                         matlab.ui.control.Knob
        grEditField_2Label_14           matlab.ui.control.Label
        grEditField_2Label_15           matlab.ui.control.Label
        Knob_23                         matlab.ui.control.Knob
        grEditField_2Label_16           matlab.ui.control.Label
        grEditField_2Label_17           matlab.ui.control.Label
        egLabel_6                       matlab.ui.control.Label
        egEditField_6                   matlab.ui.control.Spinner
        lambdaLabel_4                   matlab.ui.control.Label
        lambdaEditField_4               matlab.ui.control.Spinner
        kneticparametersPanel           matlab.ui.container.Panel
        Knob_26                         matlab.ui.control.Knob
        knLabel                         matlab.ui.control.Label
        kn                              matlab.ui.control.Spinner
        scanrateLabel                   matlab.ui.control.Label
        scanrate                        matlab.ui.control.Spinner
        kpLabel                         matlab.ui.control.Label
        kp                              matlab.ui.control.Spinner
        protonLabel                     matlab.ui.control.Label
        proton                          matlab.ui.control.Spinner
        Knob_27                         matlab.ui.control.Knob
        grEditField_2Label_18           matlab.ui.control.Label
        Knob_28                         matlab.ui.control.Knob
        grEditField_2Label_19           matlab.ui.control.Label
        Knob_29                         matlab.ui.control.Knob
        thermoelectricTab               matlab.ui.container.Tab
        tunnelingparametersPanel_2      matlab.ui.container.Panel
        Knob_30                         matlab.ui.control.Knob
        Knob_31                         matlab.ui.control.Knob
        Knob_32                         matlab.ui.control.Knob
        egLabel_7                       matlab.ui.control.Label
        egEditField_7                   matlab.ui.control.Spinner
        grLabel_3                       matlab.ui.control.Label
        grEditField_20                  matlab.ui.control.Spinner
        glLabel_3                       matlab.ui.control.Label
        glEditField_7                   matlab.ui.control.Spinner
        voltagescalePanel_8             matlab.ui.container.Panel
        voltagestepEditField_2Label     matlab.ui.control.Label
        voltagestepEditField_2          matlab.ui.control.NumericEditField
        Slider_9                        matlab.ui.control.Slider
        voltagescaleLabel_2             matlab.ui.control.Label
        voltageEditField_10             matlab.ui.control.Spinner
        resultPanel_8                   matlab.ui.container.Panel
        UIAxes_8                        matlab.ui.control.UIAxes
        UIAxeslog_8                     matlab.ui.control.UIAxes
        zerobiasthermocurrentPanel      matlab.ui.container.Panel
        Slider_13                       matlab.ui.control.Slider
        TdifferenceLabel                matlab.ui.control.Label
        alpha05EditField_7              matlab.ui.control.Spinner
        leftelectrodeLabel              matlab.ui.control.Label
        alpha05EditField_8              matlab.ui.control.Spinner
        Slider_14                       matlab.ui.control.Slider
        SimuButton_5                    matlab.ui.control.Button
        Image2_8                        matlab.ui.control.Image
        electrodetemperaturePanel       matlab.ui.container.Panel
        Slider_11                       matlab.ui.control.Slider
        leftLabel                       matlab.ui.control.Label
        alpha05EditField_5              matlab.ui.control.Spinner
        rightLabel                      matlab.ui.control.Label
        alpha05EditField_6              matlab.ui.control.Spinner
        Slider_12                       matlab.ui.control.Slider
        homeButton                      matlab.ui.control.Button
        Image3                          matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: homeButton
        function homeButtonPushed(app, event)
            delete(app.UIFigure);
            run COVERV2.mlapp;
        end

        % Image clicked function: Image5
        function Image5Clicked(app, event)
            delete(app.UIFigure);
            run COVERV2.mlapp;
        end

        % Value changing function: Knob
        function KnobValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            app.Knob.Value=roundn(value,-2);
            app.egEditField.Value=app.Knob.Value;
        end

        % Value changed function: Knob_2
        function Knob_2ValueChanged(app, event)
            value = app.Knob_2.Value;
            app.Knob_2.Value=roundn(value,-3);
            app.grEditField.Value=app.Knob_2.Value;
        end

        % Value changed function: Knob_3
        function Knob_3ValueChanged(app, event)
            value = app.Knob_3.Value;
            app.Knob_3.Value=roundn(value,-3);
            app.glEditField.Value=app.Knob_3.Value;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.Slider.Value=roundn(value,-1);
            app.voltageEditField.Value=app.Slider.Value;
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            app.Slider_2.Value=roundn(value,-1);
            app.alpha05EditField.Value=app.Slider_2.Value;
        end

        % Value changed function: Knob_5
        function Knob_5ValueChanged(app, event)
            value = app.Knob_5.Value;
            app.Knob_5.Value=roundn(value,-1);
            app.grEditField_2.Value=10^app.Knob_5.Value;
        end

        % Button pushed function: SimuButton
        function SimuButtonPushed(app, event)
            app.UIAxes.cla;
            app.UIAxeslog.cla;
            poi = app.voltagestepEditField.Value +1; %一步电压取点数
            Vh1=linspace(0,app.voltageEditField.Value,poi);%第一步扫描的电压数组（0V-2V）
            Vh4=linspace(-app.voltageEditField.Value,0,poi);%第三步扫描的电压数组（-2V-0V）
            Vh0 = [Vh4,Vh1];%求概率的电压范围
            for iV=1:2*poi
                result1tunneling=tunnelingchannel(app.egEditField.Value,app.voltageEditField_8.Value*app.gatingefficiencyEditField.Value*0.01,app.grEditField.Value,app.glEditField.Value,Vh0(iV),app.alpha05EditField.Value,300 );
                Vtunneling(iV)=result1tunneling(1);
                Itunneling(iV)=result1tunneling(2);
                %                 mm1tunneling(iV)=result1tunneling(4);%oxi1
                %                 mm2tunneling(iV)=result1tunneling(3);%red1
            end


            plot(app.UIAxes,Vh0,Itunneling,'Color',[11/255 7/255 137/255],'LineWidth',3);
            hold(app.UIAxes,"on");
            xlabel(app.UIAxes,'Voltage/V','fontsize',18)
            ylabel(app.UIAxes,'\itI/','fontsize',18)
            set(gca,'FontSize',18);
            hold(app.UIAxes,"off");
            
            
            plot(app.UIAxeslog,Vh0,log10(abs(Itunneling)),'Color',[11/255 7/255 137/255],'LineWidth',3);
            xlabel(app.UIAxeslog,'Voltage/V','fontsize',18)
            ylabel(app.UIAxeslog,'Log|\itI|','fontsize',18)
            set(gca,'FontSize',18);
            
        end

        % Button pushed function: SimuButton_2
        function SimuButton_2Pushed(app, event)
            app.UIAxes_5.cla;
            app.UIAxeslog_5.cla;
            poi = app.stepEditField_5.Value +1; %一步电压取点数
            Vh1=linspace(0,app.voltageEditField_5.Value,poi);%第一步扫描的电压数组（0V-2V）
            Vh4=linspace(-app.voltageEditField_5.Value,0,poi);%第三步扫描的电压数组（-2V-0V）
            Vh0 = [Vh4,Vh1];%求概率的电压范围
            for iV=1:2*poi
                result1hopping=hoppingchannel(app.egEditField_2.Value,app.lambdaEditField.Value,app.grEditField_2.Value*10^13,app.glEditField_2.Value*10^13,Vh0(iV),app.voltageEditField_9.Value*app.gatingefficiencyEditField_2.Value*0.01,app.alpha05EditField_3.Value,300 );
                Vhopping(iV)=result1hopping(1);
                Ihopping(iV)=result1hopping(2);
                mm1hopping(iV)=result1hopping(4);%oxi1
                mm2hopping(iV)=result1hopping(3);%red1
            end


            plot(app.UIAxes_5,Vh0,Ihopping,'Color',[11/255 7/255 137/255],'LineWidth',3);
            hold(app.UIAxes_5,"on");
            xlabel(app.UIAxes_5,'Voltage/V','fontsize',18)
            ylabel(app.UIAxes_5,'\itI/','fontsize',18)
            set(gca,'FontSize',18);
            hold(app.UIAxes_5,"off");
            
            
            plot(app.UIAxeslog_5,Vh0,log10(abs(Ihopping)),'Color',[11/255 7/255 137/255],'LineWidth',3);
            xlabel(app.UIAxeslog_5,'Voltage/V','fontsize',18)
            ylabel(app.UIAxeslog_5,'Log|\itI|','fontsize',18)
            set(gca,'FontSize',18);
        end

        % Value changed function: Knob_4
        function Knob_4ValueChanged(app, event)
            value = app.Knob_4.Value;
            app.Knob_4.Value=roundn(value,-2);
            app.egEditField_2.Value=app.Knob_4.Value;
        end

        % Value changed function: Knob_8
        function Knob_8ValueChanged(app, event)
            value = app.Knob_8.Value;
            app.Knob_8.Value=roundn(value,-1);
            app.glEditField_2.Value=10^app.Knob_8.Value;
        end

        % Value changed function: Knob_7
        function Knob_7ValueChanged(app, event)
            value = app.Knob_7.Value;
            app.Knob_7.Value=roundn(value,-2);
            app.lambdaEditField.Value=app.Knob_7.Value;
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            value = app.Slider_3.Value;
            app.Slider_3.Value=roundn(value,-1);
            app.voltageEditField_5.Value= app.Slider_3.Value;
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            value = app.Slider_4.Value;
            app.Slider_4.Value=roundn(value,-1);
            app.alpha05EditField_3.Value= app.Slider_4.Value;
        end

        % Value changed function: Knob_9
        function Knob_9ValueChanged(app, event)
            value = app.Knob_9.Value;
            app.Knob_9.Value=roundn(value,-2);
            app.egEditField_3.Value= app.Knob_9.Value;
        end

        % Value changed function: Knob_10
        function Knob_10ValueChanged(app, event)
            value = app.Knob_10.Value;
            app.Knob_10.Value=roundn(value,-1);
            app.grEditField_6.Value = 10^app.Knob_10.Value;
        end

        % Value changed function: Knob_12
        function Knob_12ValueChanged(app, event)
            value = app.Knob_12.Value;
            app.Knob_12.Value=roundn(value,-1);
            app.glEditField_3.Value= 10^app.Knob_12.Value;
        end

        % Value changed function: Knob_11
        function Knob_11ValueChanged(app, event)
            value = app.Knob_11.Value;
            app.Knob_11.Value=roundn(value,-2);
            app.lambdaEditField_2.Value=app.Knob_11.Value;
        end

        % Value changed function: Knob_13
        function Knob_13ValueChanged(app, event)
            value = app.Knob_13.Value;
            app.Knob_13.Value=roundn(value,-2);
            app.egEditField_4.Value=app.Knob_13.Value;
        end

        % Value changed function: Knob_14
        function Knob_14ValueChanged(app, event)
            value = app.Knob_14.Value;
            app.Knob_14.Value=roundn(value,-3);
            app.grEditField_10.Value=app.Knob_14.Value;
        end

        % Value changed function: Knob_15
        function Knob_15ValueChanged(app, event)
            value = app.Knob_15.Value;
            app.Knob_15.Value=roundn(value,-3);
            app.glEditField_4.Value = app.Knob_15.Value;
        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
            value = app.Slider_5.Value;
            app.Slider_5.Value=roundn(value,-1);
            app.voltageEditField_6.Value= app.Slider_5.Value;
        end

        % Button pushed function: SimuButton_3
        function SimuButton_3Pushed(app, event)
            app.UIAxes_6.cla;
            app.UIAxeslog_6.cla;
            poi = app.stepEditField_6.Value +1; %一步电压取点数
            Vh1=linspace(0,app.voltageEditField_6.Value,poi);%第一步扫描的电压数组（0V-2V）
            Vh4=linspace(-app.voltageEditField_6.Value,0,poi);%第三步扫描的电压数组（-2V-0V）
            Vh0 = [Vh4,Vh1];%求概率的电压范围
            for iV=1:2*poi
                result1tunneling1=tunnelingchannel(app.egEditField_4.Value,0,app.grEditField_10.Value,app.glEditField_4.Value,Vh0(iV),0.5,300 );
                Vtunneling1(iV)=result1tunneling1(1);
                Itunneling1(iV)=result1tunneling1(2);
                
                result1hopping1=hoppingchannel(app.egEditField_3.Value,app.lambdaEditField_2.Value,app.grEditField_6.Value*10^13,app.glEditField_3.Value*10^13,Vh0(iV),0,0.5,300 );
                Vhopping1(iV)=result1hopping1(1);
                Ihopping1(iV)=result1hopping1(2);
                %                 mm1hopping(iV)=result1hopping1(4);%oxi1
                %                 mm2hopping(iV)=result1hopping1(3);%red1
            end


            plot(app.UIAxes_6,Vh0,Itunneling1+Ihopping1,'LineWidth',3);
            hold(app.UIAxes_6,"on");
            plot(app.UIAxes_6,Vh0,Itunneling1,'Linestyle',':','Color','r','LineWidth',1.5);
            hold(app.UIAxes_6,"on");
            plot(app.UIAxes_6,Vh0,Ihopping1,'Color','b','Linestyle','--','LineWidth',1.5);
            legend(app.UIAxes_6,{'Itotal','Itunneling','Ihopping'})
            xlabel(app.UIAxes_6,'Voltage/V','fontsize',18)
            ylabel(app.UIAxes_6,'\itI/','fontsize',18)
            set(gca,'FontSize',18);
            hold(app.UIAxes_6,"off");
            
            
            plot(app.UIAxeslog_6,Vh0,log10(abs(Itunneling1+Ihopping1)),'Color',[11/255 7/255 137/255],'LineWidth',3);
            hold(app.UIAxeslog_6,"on");
            plot(app.UIAxeslog_6,Vh0,log10(abs(Itunneling1)),'Linestyle',':','Color','r','LineWidth',1.5);
            hold(app.UIAxeslog_6,"on");
            plot(app.UIAxeslog_6,Vh0,log10(abs(Ihopping1)),'Color','b','Linestyle','--','LineWidth',1.5);
            legend(app.UIAxeslog_6,{'Itotal','Itunneling','Ihopping'})
            xlabel(app.UIAxeslog_6,'Voltage/V','fontsize',18)
            ylabel(app.UIAxeslog_6,'Log|\itI|','fontsize',18)
            set(gca,'FontSize',18);
            hold(app.UIAxes_6,"off");
        end

        % Value changed function: Slider_7
        function Slider_7ValueChanged(app, event)
            value = app.Slider_7.Value;
            app.Slider_7.Value=roundn(value,-2);
            app.voltageEditField_8.Value=app.Slider_7.Value;
        end

        % Value changed function: Slider_8
        function Slider_8ValueChanged(app, event)
            value = app.Slider_8.Value;
            app.Slider_8.Value=roundn(value,-2);
            app.voltageEditField_9.Value=app.Slider_8.Value;
        end

        % Image clicked function: Image8
        function Image8Clicked(app, event)
            fig = uifigure;
            message = {['The 0V gate voltage is seen as two terminal device simulating. ' ...
                'The gating efficiency here is defined as the effective voltage drop on the molecular']};
            uialert(fig,message,'gating voltage','Icon','question');
        end

        % Image clicked function: Image8_2
        function Image8_2Clicked(app, event)
            fig = uifigure;
            message = {['The 0V gate voltage is seen as two terminal device simulating. ' ...
                'The gating efficiency here is defined as the effective voltage drop on the molecular']};
            uialert(fig,message,'gating voltage','Icon','question');
        end

        % Value changed function: Knob_16
        function Knob_16ValueChanged(app, event)
            value = app.Knob_16.Value;
            app.Knob_16.Value=roundn(value,-2);
            app.egEditField_5.Value=app.Knob_16.Value;
        end

        % Value changed function: Knob_17
        function Knob_17ValueChanged(app, event)
            value = app.Knob_17.Value;
            app.Knob_17.Value=roundn(value,-1);
            app.grEditField_11.Value=10^app.Knob_17.Value;
        end

        % Value changed function: Knob_21
        function Knob_21ValueChanged(app, event)
            value = app.Knob_21.Value;
            app.Knob_21.Value=roundn(value,-1);
            app.grEditField_14.Value=10^app.Knob_21.Value;
        end

        % Value changed function: Knob_20
        function Knob_20ValueChanged(app, event)
            value = app.Knob_20.Value;
            app.Knob_20.Value=roundn(value,-2);
            app.egEditField_6.Value=app.Knob_20.Value;
        end

        % Value changed function: Knob_19
        function Knob_19ValueChanged(app, event)
            value = app.Knob_19.Value;
            app.Knob_19.Value=roundn(value,-1);
            app.glEditField_5.Value=10^app.Knob_19.Value;
        end

        % Value changed function: Knob_23
        function Knob_23ValueChanged(app, event)
            value = app.Knob_23.Value;
            app.Knob_23.Value=roundn(value,-1);
            app.glEditField_6.Value=10^app.Knob_23.Value;
        end

        % Value changed function: Knob_18
        function Knob_18ValueChanged(app, event)
            value = app.Knob_18.Value;
            app.Knob_18.Value=roundn(value,-2);
            app.lambdaEditField_3.Value=app.Knob_18.Value;
        end

        % Value changed function: Knob_22
        function Knob_22ValueChanged(app, event)
            value = app.Knob_22.Value;
            app.Knob_22.Value=roundn(value,-2);
            app.lambdaEditField_4.Value=app.Knob_22.Value;
        end

        % Value changed function: Knob_27
        function Knob_27ValueChanged(app, event)
            value = app.Knob_27.Value;
            app.Knob_27.Value=roundn(value,-1);
            app.kn.Value=10^app.Knob_27.Value;
        end

        % Value changed function: Knob_28
        function Knob_28ValueChanged(app, event)
            value = app.Knob_28.Value;
            app.Knob_28.Value=roundn(value,-1);
            app.kp.Value=10^app.Knob_28.Value;
        end

        % Value changed function: Knob_29
        function Knob_29ValueChanged(app, event)
            value = app.Knob_29.Value;
            app.Knob_29.Value=roundn(value,-1);
            app.proton.Value=10^app.Knob_29.Value;
        end

        % Value changed function: Knob_26
        function Knob_26ValueChanged(app, event)
            value = app.Knob_26.Value;
            app.Knob_26.Value=roundn(value,-1);
            app.scanrate.Value=10^app.Knob_26.Value;
        end

        % Value changed function: Slider_6
        function Slider_6ValueChanged(app, event)
            value = app.Slider_6.Value;
            app.Slider_6.Value=roundn(value,-1);
            app.voltageEditField_7.Value= app.Slider_6.Value;
        end

        % Value changed function: kp
        function kpValueChanged(app, event)
            value = app.kp.Value;
            
        end

        % Button pushed function: SimuButton_4
        function SimuButton_4Pushed(app, event)
            app.UIAxes_7.cla;
            app.UIAxeslog_7.cla;
            poi = app.stepEditField_7.Value*app.voltageEditField_7.Value+1; %一步电压取点数
            Vh1=linspace(0,app.voltageEditField_7.Value,poi);%第一步扫描的电压数组（0V-2V）
            Vh4=linspace(-app.voltageEditField_7.Value,0,poi);%第三步扫描的电压数组（-2V-0V）
            Vh0 = [Vh4,Vh1];%求概率的电压范围
            for iV=1:2*poi
                result1=hoppingchannel(app.egEditField_5.Value,app.lambdaEditField_3.Value,app.grEditField_11.Value,app.glEditField_5.Value,Vh0(iV),0,0.5,300 );
                V(iV)=result1(1);
                I1(iV)=result1(2);
                mm1(iV)=result1(4);%oxi1
                mm2(iV)=result1(3);%red1
            end
            
            for iV=1:2*poi
                result2=hoppingchannel(app.egEditField_6.Value,app.lambdaEditField_4.Value,app.grEditField_14.Value,app.glEditField_6.Value,Vh0(iV),0,0.5,300 );
                V(iV)=result2(1);
                I2(iV)=result2(2);
                nn1(iV)=result2(4);%oxi1
                nn2(iV)=result2(3);%red1
            end
            
            kpp=app.kp.Value*app.proton.Value;
            AMOUNT=knetic(app.voltageEditField_7.Value, app.scanrate.Value,app.kn.Value,kpp,mm1,mm2,nn1,nn2,I1,I2);
            Vhall=AMOUNT(:,1);%VOLTAGE
            AQ=AMOUNT(:,2);%AMOUNT AQ
            HAQ=AMOUNT(:,3);%AMOUNT HAQ
            CurrentsAQ=AMOUNT(:,4);%AQ CHANNEL CURRENT
            CurrentsHAQ=AMOUNT(:,5);%HAQ CHANNEL CURRENT
            Currents=AMOUNT(:,6);%CURRENT


            plot(app.UIAxes_7,Vhall(1:poi,1),Currents(1:poi,1),'Color',[11/255 7/255 137/255],'LineWidth',3);
            hold(app.UIAxes_7,"on");
            plot(app.UIAxes_7,Vhall(poi+1:2*poi,1),Currents(poi+1:2*poi,1),':','Color',[114/255 0 128/255],'linewidth',3)
            hold(app.UIAxes_7,"on");
            plot(app.UIAxes_7,Vhall(2*poi+1:3*poi,1),Currents(2*poi+1:3*poi,1),'Color',[11/255 7/255 137/255],'linewidth',3)
            hold(app.UIAxes_7,"on");
            plot(app.UIAxes_7,Vhall(3*poi+1:4*poi,1),Currents(3*poi+1:4*poi,1),':','Color',[114/255 0 128/255],'linewidth',3)
            % axis([-inf,inf,-14,-7])
            xlabel(app.UIAxes_7,'Voltage/V','fontsize',18)
            ylabel(app.UIAxes_7,'\itI/','fontsize',18)
            legend(app.UIAxes_7,{'Forward','Reverse'},'FontSize',6);
            set(gca,'FontSize',18);
            hold(app.UIAxes_7,"off");
            
            plot(app.UIAxeslog_7,Vhall(1:poi,1),log10(abs(Currents(1:poi,1))),'Color',[11/255 7/255 137/255],'LineWidth',3);
            hold(app.UIAxeslog_7,"on");
            plot(app.UIAxeslog_7,Vhall(poi+1:2*poi,1),log10(abs(Currents(poi+1:2*poi,1))),':','Color',[114/255 0 128/255],'linewidth',3)
            hold(app.UIAxeslog_7,"on");
            plot(app.UIAxeslog_7,Vhall(2*poi+1:3*poi,1),log10(abs(Currents(2*poi+1:3*poi,1))),'Color',[11/255 7/255 137/255],'linewidth',3)
            hold(app.UIAxeslog_7,"on");
            plot(app.UIAxeslog_7,Vhall(3*poi+1:4*poi,1),log10(abs(Currents(3*poi+1:4*poi,1))),':','Color',[114/255 0 128/255],'linewidth',3)
            
            xlabel(app.UIAxeslog_7,'Voltage/V','fontsize',18)
            ylabel(app.UIAxeslog_7,'Log|\itI|','fontsize',18)
            legend(app.UIAxeslog_7,{'Forward','Reverse'},'FontSize',6);
            set(gca,'FontSize',18);
        end

        % Value changed function: Knob_30
        function Knob_30ValueChanged(app, event)
            value = app.Knob_30.Value;
            app.Knob_30.Value=roundn(value,-2);
            app.egEditField_7.Value=app.Knob_30.Value;
        end

        % Value changed function: Knob_31
        function Knob_31ValueChanged(app, event)
            value = app.Knob_31.Value;
            app.Knob_31.Value=roundn(value,-3);
            app.grEditField_20.Value=app.Knob_31.Value;
        end

        % Value changed function: Knob_32
        function Knob_32ValueChanged(app, event)
            value = app.Knob_32.Value;
            app.Knob_32.Value=roundn(value,-3);
            app.glEditField_7.Value=app.Knob_32.Value;
        end

        % Value changed function: Slider_9
        function Slider_9ValueChanged(app, event)
            value = app.Slider_9.Value;
            app.Slider_9.Value=roundn(value,-1);
            app.voltageEditField_10.Value=app.Slider_9.Value;
        end

        % Callback function
        function Slider_10ValueChanged(app, event)
            value = app.Slider_10.Value;
            app.Slider_10.Value=roundn(value,-1);
            app.alpha05EditField_4.Value= app.Slider_10.Value;
        end

        % Button pushed function: SimuButton_5
        function SimuButton_5Pushed(app, event)
            app.UIAxes_8.cla;
            app.UIAxeslog_8.cla;
            poi = app.voltagestepEditField_2.Value*app.voltageEditField_10.Value+1; %一步电压取点数
            Vh1=linspace(0,app.voltageEditField_10.Value,poi);%第一步扫描的电压数组（0V-2V）
            Vh4=linspace(-app.voltageEditField_10.Value,0,poi);%第三步扫描的电压数组（-2V-0V）
            Vh0 = [Vh4,Vh1];%求概率的电压范围
            e=1.60217*(10^(-19));
            h=6.62607004*(10^(-34));
            hbar=h/(2*pi);
            kB=0.000086;
            kTl=kB*app.alpha05EditField_5.Value;
            kTr=kB*app.alpha05EditField_6.Value;
            g1=app.grEditField_20.Value;
            g2=app.glEditField_7.Value;
            g=g1+g2;
            Eg=app.egEditField_7.Value;
            u=0;
            alpha=0.5;
            NE=16001;
            E=linspace(-8,8,NE);
            dE=E(2)-E(1);
            D=(g/(2*pi))./(((E-(Eg+u)).^2)+((g/2)^2));% Lorentzian Density of states per eV
            D=D./(dE*sum(D));%Normalizing to one
            
            for iV=1:2*poi
                UL=(alpha*Vh0(iV));
                UR=((1-alpha)*Vh0(iV));
                f1=1./(1+exp((E+UL)./kTl));
                f2=1./(1+exp((E-UR)./kTr));
                current(iV)=-dE*e*e/h*(sum(D.*(f1-f2)))*2*pi*(g1*g2/g);
                %             N(iV)=dE*sum(D.*((g1*f1+g2*f2)/g));
                %             ox(iV)=1-N(iV);
            end
            
            poiT = 2*app.alpha05EditField_7.Value+1; %一步电压取点数
            VhT0 =linspace(app.alpha05EditField_8.Value-app.alpha05EditField_7.Value,app.alpha05EditField_8.Value+app.alpha05EditField_7.Value,poiT);%求概率的电压范围
            kTTl=kB*app.alpha05EditField_8.Value;
            VhT1=linspace(-app.alpha05EditField_7.Value,app.alpha05EditField_7.Value,poiT);
            for iVT=1:poiT
                kTTr=kB*VhT0(iVT);
                UL=0;
                UR=0;
                f1T=1./(1+exp((E+UL)./kTTl));
                f2T=1./(1+exp((E-UR)./kTTr));
                currentT(iVT)=-dE*e*e/h*(sum(D.*(f1T-f2T)))*2*pi*(g1*g2/g);
                %             N(iVT)=dE*sum(D.*((g1*f1+g2*f2)/g));
                %             ox(iVT)=1-N(iVT);
            end
            
            plot(app.UIAxes_8,Vh0,current,'Color',[11/255 7/255 137/255],'LineWidth',3);
            hold(app.UIAxes_8,"on");
            xlabel(app.UIAxes_8,'Voltage/V','fontsize',18)
            ylabel(app.UIAxes_8,'\itI/','fontsize',18)
            set(gca,'FontSize',18);
            hold(app.UIAxes_8,"off");
            
            plot(app.UIAxeslog_8,VhT1,currentT,'Color',[11/255 7/255 137/255],'LineWidth',3);
            hold(app.UIAxeslog_8,"on");
            xlabel(app.UIAxeslog_8,'Temperature Difference/K','fontsize',18)
            ylabel(app.UIAxeslog_8,'\itI/','fontsize',18)
            set(gca,'FontSize',18);
            hold(app.UIAxeslog_8,"off");
        end

        % Value changed function: Slider_11
        function Slider_11ValueChanged(app, event)
            value = app.Slider_11.Value;
            app.Slider_11.Value=roundn(value,-1);
            app.alpha05EditField_5.Value= app.Slider_11.Value;
        end

        % Value changed function: Slider_12
        function Slider_12ValueChanged(app, event)
            value = app.Slider_12.Value;
            app.Slider_12.Value=roundn(value,-1);
            app.alpha05EditField_6.Value= app.Slider_12.Value;
        end

        % Value changed function: Slider_13
        function Slider_13ValueChanged(app, event)
            value = app.Slider_13.Value;
            app.Slider_13.Value=roundn(value,0);
            app.alpha05EditField_8.Value= app.Slider_13.Value;
        end

        % Value changed function: Slider_14
        function Slider_14ValueChanged(app, event)
            value = app.Slider_14.Value;
            app.Slider_14.Value=roundn(value,0);
            app.alpha05EditField_7.Value= app.Slider_14.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.tunnelingTab = uitab(app.TabGroup);
            app.tunnelingTab.Title = 'tunneling';
            app.tunnelingparametersPanel = uipanel(app.tunnelingTab);
            app.tunnelingparametersPanel.Title = 'tunneling parameters';
            app.Knob = uiknob(app.tunnelingparametersPanel, 'continuous');
            app.Knob.Limits = [0 1];
            app.Knob.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.ValueChangingFcn = createCallbackFcn(app, @KnobValueChanging, true);
            app.Knob.Value = 0.5;
            app.Knob_2 = uiknob(app.tunnelingparametersPanel, 'continuous');
            app.Knob_2.Limits = [0 0.1];
            app.Knob_2.MajorTicks = [0 0.02 0.04 0.06 0.08 0.1];
            app.Knob_2.ValueChangedFcn = createCallbackFcn(app, @Knob_2ValueChanged, true);
            app.Knob_2.Value = 0.005;
            app.Knob_3 = uiknob(app.tunnelingparametersPanel, 'continuous');
            app.Knob_3.Limits = [0 0.1];
            app.Knob_3.MajorTicks = [0 0.02 0.04 0.06 0.08 0.1];
            app.Knob_3.ValueChangedFcn = createCallbackFcn(app, @Knob_3ValueChanged, true);
            app.Knob_3.Value = 0.005;
            app.egLabel = uilabel(app.tunnelingparametersPanel);
            app.egLabel.Text = 'eg';
            app.egEditField = uispinner(app.tunnelingparametersPanel);
            app.egEditField.Step = 0.001;
            app.egEditField.Limits = [0 5];
            app.egEditField.Value = 0.5;
            app.grLabel = uilabel(app.tunnelingparametersPanel);
            app.grLabel.Text = 'gr';
            app.grEditField = uispinner(app.tunnelingparametersPanel);
            app.grEditField.Step = 0.001;
            app.grEditField.Limits = [0.001 5];
            app.grEditField.Value = 0.005;
            app.glLabel = uilabel(app.tunnelingparametersPanel);
            app.glLabel.Text = 'gl';
            app.glEditField = uispinner(app.tunnelingparametersPanel);
            app.glEditField.Step = 0.001;
            app.glEditField.Limits = [0.001 5];
            app.glEditField.Value = 0.005;
            app.voltagescalePanel = uipanel(app.tunnelingTab);
            app.voltagescalePanel.Title = 'voltage scale';
            app.voltagestepEditFieldLabel = uilabel(app.voltagescalePanel);
            app.voltagestepEditFieldLabel.Text = 'voltage step';
            app.voltagestepEditField = uieditfield(app.voltagescalePanel, 'numeric');
            app.voltagestepEditField.Value = 100;
            app.Slider = uislider(app.voltagescalePanel);
            app.Slider.Limits = [0 5];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 1.5;
            app.voltagescaleLabel = uilabel(app.voltagescalePanel);
            app.voltagescaleLabel.Text = 'voltage scale';
            app.voltageEditField = uispinner(app.voltagescalePanel);
            app.voltageEditField.Step = 0.1;
            app.voltageEditField.Limits = [0 5];
            app.voltageEditField.Value = 1.5;
            app.resultPanel = uipanel(app.tunnelingTab);
            app.resultPanel.Title = 'result';
            app.UIAxes = uiaxes(app.resultPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Voltage/V')
            ylabel(app.UIAxes, 'I/A')
            app.UIAxeslog = uiaxes(app.resultPanel);
            title(app.UIAxeslog, '')
            xlabel(app.UIAxeslog, 'Voltage/V')
            ylabel(app.UIAxeslog, 'Log(I)')
            app.rectificationPanel = uipanel(app.tunnelingTab);
            app.rectificationPanel.Title = 'rectification';
            app.Slider_2 = uislider(app.rectificationPanel);
            app.Slider_2.Limits = [0 1];
            app.Slider_2.MajorTicks = [0 0.5 1];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Slider_2.Value = 0.5;
            app.alphaLabel = uilabel(app.rectificationPanel);
            app.alphaLabel.Text = 'alpha';
            app.alpha05EditField = uispinner(app.rectificationPanel);
            app.alpha05EditField.Step = 0.1;
            app.alpha05EditField.Limits = [0 1];
            app.alpha05EditField.Value = 0.5;
            app.SimuButton = uibutton(app.tunnelingTab, 'push');
            app.SimuButton.ButtonPushedFcn = createCallbackFcn(app, @SimuButtonPushed, true);
            app.SimuButton.Text = 'Simu';
            app.Image2 = uiimage(app.tunnelingTab);
            app.Image2.ImageSource = 'xiaohui.jpg';
            app.gatevoltagePanel = uipanel(app.tunnelingTab);
            app.gatevoltagePanel.Title = 'gate voltage';
            app.Slider_7 = uislider(app.gatevoltagePanel);
            app.Slider_7.Limits = [-1 1];
            app.Slider_7.MajorTicks = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.Slider_7.ValueChangedFcn = createCallbackFcn(app, @Slider_7ValueChanged, true);
            app.gatevoltageLabel = uilabel(app.gatevoltagePanel);
            app.gatevoltageLabel.Text = 'gate voltage';
            app.voltageEditField_8 = uispinner(app.gatevoltagePanel);
            app.voltageEditField_8.Step = 0.01;
            app.voltageEditField_8.Limits = [-5 5];
            app.gatingefficiencyEditFieldLabel = uilabel(app.gatevoltagePanel);
            app.gatingefficiencyEditFieldLabel.Text = 'gating efficiency';
            app.gatingefficiencyEditField = uieditfield(app.gatevoltagePanel, 'numeric');
            app.gatingefficiencyEditField.Limits = [0 100];
            app.gatingefficiencyEditField.Value = 100;
            app.Image8 = uiimage(app.gatevoltagePanel);
            app.Image8.ImageClickedFcn = createCallbackFcn(app, @Image8Clicked, true);
            app.Image8.ImageSource = 'wenhap.jpg';
            app.hoppingTab = uitab(app.TabGroup);
            app.hoppingTab.Title = 'hopping';
            app.hoppingparametersPanel_2 = uipanel(app.hoppingTab);
            app.hoppingparametersPanel_2.Title = 'hopping parameters';
            app.grEditField_2Label = uilabel(app.hoppingparametersPanel_2);
            app.grEditField_2Label.Text = 'gr';
            app.grEditField_2 = uieditfield(app.hoppingparametersPanel_2, 'numeric');
            app.grEditField_2.Value = 1;
            app.glEditField_2Label = uilabel(app.hoppingparametersPanel_2);
            app.glEditField_2Label.Text = 'gl';
            app.glEditField_2 = uieditfield(app.hoppingparametersPanel_2, 'numeric');
            app.glEditField_2.Value = 1;
            app.Knob_4 = uiknob(app.hoppingparametersPanel_2, 'continuous');
            app.Knob_4.Limits = [0 1];
            app.Knob_4.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.Knob_4.ValueChangedFcn = createCallbackFcn(app, @Knob_4ValueChanged, true);
            app.Knob_4.Value = 0.5;
            app.Knob_5 = uiknob(app.hoppingparametersPanel_2, 'continuous');
            app.Knob_5.Limits = [-3 3];
            app.Knob_5.ValueChangedFcn = createCallbackFcn(app, @Knob_5ValueChanged, true);
            app.Knob_5.Value = 1;
            app.Knob_7 = uiknob(app.hoppingparametersPanel_2, 'continuous');
            app.Knob_7.Limits = [0 1];
            app.Knob_7.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.Knob_7.ValueChangedFcn = createCallbackFcn(app, @Knob_7ValueChanged, true);
            app.Knob_7.Value = 0.5;
            app.grEditField_2Label_2 = uilabel(app.hoppingparametersPanel_2);
            app.grEditField_2Label_2.Text = '*10^13';
            app.grEditField_2Label_3 = uilabel(app.hoppingparametersPanel_2);
            app.grEditField_2Label_3.Text = '*10^13';
            app.Knob_8 = uiknob(app.hoppingparametersPanel_2, 'continuous');
            app.Knob_8.Limits = [-3 3];
            app.Knob_8.ValueChangedFcn = createCallbackFcn(app, @Knob_8ValueChanged, true);
            app.Knob_8.Value = 1;
            app.grEditField_2Label_4 = uilabel(app.hoppingparametersPanel_2);
            app.grEditField_2Label_4.Text = 'LOG';
            app.grEditField_2Label_5 = uilabel(app.hoppingparametersPanel_2);
            app.grEditField_2Label_5.Text = 'LOG';
            app.egLabel_2 = uilabel(app.hoppingparametersPanel_2);
            app.egLabel_2.Text = 'eg';
            app.egEditField_2 = uispinner(app.hoppingparametersPanel_2);
            app.egEditField_2.Step = 0.001;
            app.egEditField_2.Limits = [0 5];
            app.egEditField_2.Value = 0.5;
            app.lambdaLabel = uilabel(app.hoppingparametersPanel_2);
            app.lambdaLabel.Text = 'lambda';
            app.lambdaEditField = uispinner(app.hoppingparametersPanel_2);
            app.lambdaEditField.Step = 0.001;
            app.lambdaEditField.Limits = [0 5];
            app.lambdaEditField.Value = 0.5;
            app.voltagescalePanel_5 = uipanel(app.hoppingTab);
            app.voltagescalePanel_5.Title = 'voltage scale';
            app.stepEditField_5Label = uilabel(app.voltagescalePanel_5);
            app.stepEditField_5Label.Text = 'step';
            app.stepEditField_5 = uieditfield(app.voltagescalePanel_5, 'numeric');
            app.stepEditField_5.Value = 100;
            app.Slider_3 = uislider(app.voltagescalePanel_5);
            app.Slider_3.Limits = [0 5];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.Value = 1.5;
            app.voltageLabel_2 = uilabel(app.voltagescalePanel_5);
            app.voltageLabel_2.Text = 'voltage';
            app.voltageEditField_5 = uispinner(app.voltagescalePanel_5);
            app.voltageEditField_5.Step = 0.1;
            app.voltageEditField_5.Limits = [0 5];
            app.voltageEditField_5.Value = 1.5;
            app.resultPanel_5 = uipanel(app.hoppingTab);
            app.resultPanel_5.Title = 'result';
            app.UIAxes_5 = uiaxes(app.resultPanel_5);
            title(app.UIAxes_5, '')
            xlabel(app.UIAxes_5, 'Voltage/V')
            ylabel(app.UIAxes_5, 'I/A')
            app.UIAxeslog_5 = uiaxes(app.resultPanel_5);
            title(app.UIAxeslog_5, '')
            xlabel(app.UIAxeslog_5, 'Voltage/V')
            ylabel(app.UIAxeslog_5, 'Log(I)')
            app.rectificationPanel_3 = uipanel(app.hoppingTab);
            app.rectificationPanel_3.Title = 'rectification';
            app.Slider_4 = uislider(app.rectificationPanel_3);
            app.Slider_4.Limits = [0 1];
            app.Slider_4.MajorTicks = [0 0.5 1];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_4.MinorTicks = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9];
            app.Slider_4.Value = 0.5;
            app.alphaLabel_2 = uilabel(app.rectificationPanel_3);
            app.alphaLabel_2.Text = 'alpha';
            app.alpha05EditField_3 = uispinner(app.rectificationPanel_3);
            app.alpha05EditField_3.Step = 0.1;
            app.alpha05EditField_3.Limits = [0 1];
            app.alpha05EditField_3.Value = 0.5;
            app.SimuButton_2 = uibutton(app.hoppingTab, 'push');
            app.SimuButton_2.ButtonPushedFcn = createCallbackFcn(app, @SimuButton_2Pushed, true);
            app.SimuButton_2.Text = 'Simu';
            app.Image2_5 = uiimage(app.hoppingTab);
            app.Image2_5.ImageSource = 'xiaohui.jpg';
            app.gatevoltagePanel_2 = uipanel(app.hoppingTab);
            app.gatevoltagePanel_2.Title = 'gate voltage';
            app.Slider_8 = uislider(app.gatevoltagePanel_2);
            app.Slider_8.Limits = [-1 1];
            app.Slider_8.MajorTicks = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.Slider_8.ValueChangedFcn = createCallbackFcn(app, @Slider_8ValueChanged, true);
            app.gatevoltageLabel_2 = uilabel(app.gatevoltagePanel_2);
            app.gatevoltageLabel_2.Text = 'gate voltage';
            app.voltageEditField_9 = uispinner(app.gatevoltagePanel_2);
            app.voltageEditField_9.Step = 0.01;
            app.voltageEditField_9.Limits = [-5 5];
            app.gatingefficiencyEditField_2Label = uilabel(app.gatevoltagePanel_2);
            app.gatingefficiencyEditField_2Label.Text = 'gating efficiency';
            app.gatingefficiencyEditField_2 = uieditfield(app.gatevoltagePanel_2, 'numeric');
            app.gatingefficiencyEditField_2.Limits = [0 100];
            app.gatingefficiencyEditField_2.Value = 100;
            app.Image8_2 = uiimage(app.gatevoltagePanel_2);
            app.Image8_2.ImageClickedFcn = createCallbackFcn(app, @Image8_2Clicked, true);
            app.Image8_2.ImageSource = 'wenhap.jpg';
            app.mixedtransportTab = uitab(app.TabGroup);
            app.mixedtransportTab.Title = 'mixed transport';
            app.hoppingchannelPanel = uipanel(app.mixedtransportTab);
            app.hoppingchannelPanel.Title = 'hopping channel';
            app.grEditField_6Label = uilabel(app.hoppingchannelPanel);
            app.grEditField_6Label.Text = 'gr';
            app.grEditField_6 = uieditfield(app.hoppingchannelPanel, 'numeric');
            app.grEditField_6.Value = 1;
            app.glEditField_3Label = uilabel(app.hoppingchannelPanel);
            app.glEditField_3Label.Text = 'gl';
            app.glEditField_3 = uieditfield(app.hoppingchannelPanel, 'numeric');
            app.glEditField_3.Value = 1;
            app.Knob_9 = uiknob(app.hoppingchannelPanel, 'continuous');
            app.Knob_9.Limits = [0 1];
            app.Knob_9.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.Knob_9.ValueChangedFcn = createCallbackFcn(app, @Knob_9ValueChanged, true);
            app.Knob_9.Value = 0.5;
            app.Knob_10 = uiknob(app.hoppingchannelPanel, 'continuous');
            app.Knob_10.Limits = [-3 3];
            app.Knob_10.ValueChangedFcn = createCallbackFcn(app, @Knob_10ValueChanged, true);
            app.Knob_10.Value = 1;
            app.Knob_11 = uiknob(app.hoppingchannelPanel, 'continuous');
            app.Knob_11.Limits = [0 1];
            app.Knob_11.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.Knob_11.ValueChangedFcn = createCallbackFcn(app, @Knob_11ValueChanged, true);
            app.Knob_11.Value = 0.5;
            app.grEditField_2Label_6 = uilabel(app.hoppingchannelPanel);
            app.grEditField_2Label_6.Text = '*10^13';
            app.grEditField_2Label_7 = uilabel(app.hoppingchannelPanel);
            app.grEditField_2Label_7.Text = '*10^13';
            app.Knob_12 = uiknob(app.hoppingchannelPanel, 'continuous');
            app.Knob_12.Limits = [-3 3];
            app.Knob_12.ValueChangedFcn = createCallbackFcn(app, @Knob_12ValueChanged, true);
            app.Knob_12.Value = 1;
            app.grEditField_2Label_8 = uilabel(app.hoppingchannelPanel);
            app.grEditField_2Label_8.Text = 'LOG';
            app.grEditField_2Label_9 = uilabel(app.hoppingchannelPanel);
            app.grEditField_2Label_9.Text = 'LOG';
            app.egLabel_3 = uilabel(app.hoppingchannelPanel);
            app.egLabel_3.Text = 'eg';
            app.egEditField_3 = uispinner(app.hoppingchannelPanel);
            app.egEditField_3.Step = 0.001;
            app.egEditField_3.Limits = [0 5];
            app.egEditField_3.Value = 0.5;
            app.lambdaLabel_2 = uilabel(app.hoppingchannelPanel);
            app.lambdaLabel_2.Text = 'lambda';
            app.lambdaEditField_2 = uispinner(app.hoppingchannelPanel);
            app.lambdaEditField_2.Step = 0.001;
            app.lambdaEditField_2.Limits = [0 5];
            app.lambdaEditField_2.Value = 0.5;
            app.voltagescalePanel_6 = uipanel(app.mixedtransportTab);
            app.voltagescalePanel_6.Title = 'voltage scale';
            app.stepEditField_6Label = uilabel(app.voltagescalePanel_6);
            app.stepEditField_6Label.Text = 'step';
            app.stepEditField_6 = uieditfield(app.voltagescalePanel_6, 'numeric');
            app.stepEditField_6.Value = 100;
            app.Slider_5 = uislider(app.voltagescalePanel_6);
            app.Slider_5.Limits = [0 5];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.Slider_5.Value = 1.5;
            app.voltageLabel_3 = uilabel(app.voltagescalePanel_6);
            app.voltageLabel_3.Text = 'voltage';
            app.voltageEditField_6 = uispinner(app.voltagescalePanel_6);
            app.voltageEditField_6.Step = 0.1;
            app.voltageEditField_6.Limits = [0 5];
            app.voltageEditField_6.Value = 1.5;
            app.resultPanel_6 = uipanel(app.mixedtransportTab);
            app.resultPanel_6.Title = 'result';
            app.UIAxes_6 = uiaxes(app.resultPanel_6);
            title(app.UIAxes_6, '')
            xlabel(app.UIAxes_6, 'Voltage/V')
            ylabel(app.UIAxes_6, 'I/A')
            app.UIAxeslog_6 = uiaxes(app.resultPanel_6);
            title(app.UIAxeslog_6, '')
            xlabel(app.UIAxeslog_6, 'Voltage/V')
            ylabel(app.UIAxeslog_6, 'Log(I)')
            app.SimuButton_3 = uibutton(app.mixedtransportTab, 'push');
            app.SimuButton_3.ButtonPushedFcn = createCallbackFcn(app, @SimuButton_3Pushed, true);
            app.SimuButton_3.Text = 'Simu';
            app.Image2_6 = uiimage(app.mixedtransportTab);
            app.Image2_6.ImageSource = 'xiaohui.jpg';
            app.tunnelingchannelPanel = uipanel(app.mixedtransportTab);
            app.tunnelingchannelPanel.Title = 'tunneling channel';
            app.Knob_13 = uiknob(app.tunnelingchannelPanel, 'continuous');
            app.Knob_13.Limits = [0 1];
            app.Knob_13.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.Knob_13.ValueChangedFcn = createCallbackFcn(app, @Knob_13ValueChanged, true);
            app.Knob_13.Value = 0.5;
            app.Knob_14 = uiknob(app.tunnelingchannelPanel, 'continuous');
            app.Knob_14.Limits = [0 0.1];
            app.Knob_14.MajorTicks = [0 0.02 0.04 0.06 0.08 0.1];
            app.Knob_14.ValueChangedFcn = createCallbackFcn(app, @Knob_14ValueChanged, true);
            app.Knob_14.Value = 0.005;
            app.Knob_15 = uiknob(app.tunnelingchannelPanel, 'continuous');
            app.Knob_15.Limits = [0 0.1];
            app.Knob_15.MajorTicks = [0 0.02 0.04 0.06 0.08 0.1];
            app.Knob_15.ValueChangedFcn = createCallbackFcn(app, @Knob_15ValueChanged, true);
            app.Knob_15.Value = 0.005;
            app.egLabel_4 = uilabel(app.tunnelingchannelPanel);
            app.egLabel_4.Text = 'eg';
            app.egEditField_4 = uispinner(app.tunnelingchannelPanel);
            app.egEditField_4.Step = 0.001;
            app.egEditField_4.Limits = [0 5];
            app.egEditField_4.Value = 0.5;
            app.grLabel_2 = uilabel(app.tunnelingchannelPanel);
            app.grLabel_2.Text = 'gr';
            app.grEditField_10 = uispinner(app.tunnelingchannelPanel);
            app.grEditField_10.Step = 0.001;
            app.grEditField_10.Limits = [0.001 5];
            app.grEditField_10.Value = 0.005;
            app.glLabel_2 = uilabel(app.tunnelingchannelPanel);
            app.glLabel_2.Text = 'gl';
            app.glEditField_4 = uispinner(app.tunnelingchannelPanel);
            app.glEditField_4.Step = 0.001;
            app.glEditField_4.Limits = [0.001 5];
            app.glEditField_4.Value = 0.005;
            app.nonsteadystateTab = uitab(app.TabGroup);
            app.nonsteadystateTab.Title = 'nonsteady state';
            app.resultPanel_7 = uipanel(app.nonsteadystateTab);
            app.resultPanel_7.Title = 'result';
            app.UIAxes_7 = uiaxes(app.resultPanel_7);
            title(app.UIAxes_7, '')
            xlabel(app.UIAxes_7, 'Voltage/V')
            ylabel(app.UIAxes_7, 'I/A')
            app.UIAxeslog_7 = uiaxes(app.resultPanel_7);
            title(app.UIAxeslog_7, '')
            xlabel(app.UIAxeslog_7, 'Voltage/V')
            ylabel(app.UIAxeslog_7, 'Log(I)')
            app.SimuButton_4 = uibutton(app.nonsteadystateTab, 'push');
            app.SimuButton_4.ButtonPushedFcn = createCallbackFcn(app, @SimuButton_4Pushed, true);
            app.SimuButton_4.Text = 'Simu';
            app.Image2_7 = uiimage(app.nonsteadystateTab);
            app.Image2_7.ImageSource = 'xiaohui.jpg';
            app.voltagescalePanel_7 = uipanel(app.nonsteadystateTab);
            app.voltagescalePanel_7.Title = 'voltage scale';
            app.stepEditField_7Label = uilabel(app.voltagescalePanel_7);
            app.stepEditField_7Label.Text = 'step';
            app.stepEditField_7 = uieditfield(app.voltagescalePanel_7, 'numeric');
            app.stepEditField_7.Value = 100;
            app.Slider_6 = uislider(app.voltagescalePanel_7);
            app.Slider_6.Limits = [0 5];
            app.Slider_6.ValueChangedFcn = createCallbackFcn(app, @Slider_6ValueChanged, true);
            app.Slider_6.Value = 1.5;
            app.voltageLabel_4 = uilabel(app.voltagescalePanel_7);
            app.voltageLabel_4.Text = 'voltage';
            app.voltageEditField_7 = uispinner(app.voltagescalePanel_7);
            app.voltageEditField_7.Step = 0.1;
            app.voltageEditField_7.Limits = [0 5];
            app.voltageEditField_7.Value = 1.5;
            app.channel1Panel = uipanel(app.nonsteadystateTab);
            app.channel1Panel.Title = 'channel1';
            app.grEditField_11Label = uilabel(app.channel1Panel);
            app.grEditField_11Label.Text = 'gr';
            app.grEditField_11 = uieditfield(app.channel1Panel, 'numeric');
            app.grEditField_11.Value = 1;
            app.glEditField_5Label = uilabel(app.channel1Panel);
            app.glEditField_5Label.Text = 'gl';
            app.glEditField_5 = uieditfield(app.channel1Panel, 'numeric');
            app.glEditField_5.Value = 1;
            app.Knob_16 = uiknob(app.channel1Panel, 'continuous');
            app.Knob_16.Limits = [0 1];
            app.Knob_16.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.Knob_16.ValueChangedFcn = createCallbackFcn(app, @Knob_16ValueChanged, true);
            app.Knob_16.Value = 0.3;
            app.Knob_17 = uiknob(app.channel1Panel, 'continuous');
            app.Knob_17.Limits = [-3 3];
            app.Knob_17.ValueChangedFcn = createCallbackFcn(app, @Knob_17ValueChanged, true);
            app.Knob_17.Value = 1;
            app.Knob_18 = uiknob(app.channel1Panel, 'continuous');
            app.Knob_18.Limits = [0 1];
            app.Knob_18.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.Knob_18.ValueChangedFcn = createCallbackFcn(app, @Knob_18ValueChanged, true);
            app.Knob_18.Value = 0.8;
            app.grEditField_2Label_10 = uilabel(app.channel1Panel);
            app.grEditField_2Label_10.Text = '*10^13';
            app.grEditField_2Label_11 = uilabel(app.channel1Panel);
            app.grEditField_2Label_11.Text = '*10^13';
            app.Knob_19 = uiknob(app.channel1Panel, 'continuous');
            app.Knob_19.Limits = [-3 3];
            app.Knob_19.ValueChangedFcn = createCallbackFcn(app, @Knob_19ValueChanged, true);
            app.Knob_19.Value = 1;
            app.grEditField_2Label_12 = uilabel(app.channel1Panel);
            app.grEditField_2Label_12.Text = 'LOG';
            app.grEditField_2Label_13 = uilabel(app.channel1Panel);
            app.grEditField_2Label_13.Text = 'LOG';
            app.egLabel_5 = uilabel(app.channel1Panel);
            app.egLabel_5.Text = 'eg';
            app.egEditField_5 = uispinner(app.channel1Panel);
            app.egEditField_5.Step = 0.001;
            app.egEditField_5.Limits = [0 5];
            app.egEditField_5.Value = 0.3;
            app.lambdaLabel_3 = uilabel(app.channel1Panel);
            app.lambdaLabel_3.Text = 'lambda';
            app.lambdaEditField_3 = uispinner(app.channel1Panel);
            app.lambdaEditField_3.Step = 0.001;
            app.lambdaEditField_3.Limits = [0 5];
            app.lambdaEditField_3.Value = 0.8;
            app.channel2Panel = uipanel(app.nonsteadystateTab);
            app.channel2Panel.Title = 'channel2';
            app.grEditField_14Label = uilabel(app.channel2Panel);
            app.grEditField_14Label.Text = 'gr';
            app.grEditField_14 = uieditfield(app.channel2Panel, 'numeric');
            app.grEditField_14.Value = 0.1;
            app.glEditField_6Label = uilabel(app.channel2Panel);
            app.glEditField_6Label.Text = 'gl';
            app.glEditField_6 = uieditfield(app.channel2Panel, 'numeric');
            app.glEditField_6.Value = 0.01;
            app.Knob_20 = uiknob(app.channel2Panel, 'continuous');
            app.Knob_20.Limits = [0 1];
            app.Knob_20.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.Knob_20.ValueChangedFcn = createCallbackFcn(app, @Knob_20ValueChanged, true);
            app.Knob_20.Value = 0.2;
            app.Knob_21 = uiknob(app.channel2Panel, 'continuous');
            app.Knob_21.Limits = [-3 3];
            app.Knob_21.ValueChangedFcn = createCallbackFcn(app, @Knob_21ValueChanged, true);
            app.Knob_21.Value = -1;
            app.Knob_22 = uiknob(app.channel2Panel, 'continuous');
            app.Knob_22.Limits = [0 1];
            app.Knob_22.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.Knob_22.ValueChangedFcn = createCallbackFcn(app, @Knob_22ValueChanged, true);
            app.Knob_22.Value = 0.9;
            app.grEditField_2Label_14 = uilabel(app.channel2Panel);
            app.grEditField_2Label_14.Text = '*10^13';
            app.grEditField_2Label_15 = uilabel(app.channel2Panel);
            app.grEditField_2Label_15.Text = '*10^13';
            app.Knob_23 = uiknob(app.channel2Panel, 'continuous');
            app.Knob_23.Limits = [-3 3];
            app.Knob_23.ValueChangedFcn = createCallbackFcn(app, @Knob_23ValueChanged, true);
            app.Knob_23.Value = -2;
            app.grEditField_2Label_16 = uilabel(app.channel2Panel);
            app.grEditField_2Label_16.Text = 'LOG';
            app.grEditField_2Label_17 = uilabel(app.channel2Panel);
            app.grEditField_2Label_17.Text = 'LOG';
            app.egLabel_6 = uilabel(app.channel2Panel);
            app.egLabel_6.Text = 'eg';
            app.egEditField_6 = uispinner(app.channel2Panel);
            app.egEditField_6.Step = 0.001;
            app.egEditField_6.Limits = [0 5];
            app.egEditField_6.Value = 0.2;
            app.lambdaLabel_4 = uilabel(app.channel2Panel);
            app.lambdaLabel_4.Text = 'lambda';
            app.lambdaEditField_4 = uispinner(app.channel2Panel);
            app.lambdaEditField_4.Step = 0.001;
            app.lambdaEditField_4.Limits = [0 5];
            app.lambdaEditField_4.Value = 0.9;
            app.kneticparametersPanel = uipanel(app.nonsteadystateTab);
            app.kneticparametersPanel.Title = 'knetic parameters';
            app.Knob_26 = uiknob(app.kneticparametersPanel, 'continuous');
            app.Knob_26.Limits = [0 10];
            app.Knob_26.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.Knob_26.ValueChangedFcn = createCallbackFcn(app, @Knob_26ValueChanged, true);
            app.Knob_26.Value = 0.5;
            app.knLabel = uilabel(app.kneticparametersPanel);
            app.knLabel.Text = 'kn';
            app.kn = uispinner(app.kneticparametersPanel);
            app.kn.Step = 0.001;
            app.kn.Limits = [0 Inf];
            app.kn.Value = 0.05;
            app.scanrateLabel = uilabel(app.kneticparametersPanel);
            app.scanrateLabel.Text = 'scan rate';
            app.scanrate = uispinner(app.kneticparametersPanel);
            app.scanrate.Step = 0.1;
            app.scanrate.Limits = [0 Inf];
            app.scanrate.Value = 0.5;
            app.kpLabel = uilabel(app.kneticparametersPanel);
            app.kpLabel.Text = 'kp';
            app.kp = uispinner(app.kneticparametersPanel);
            app.kp.Limits = [0 Inf];
            app.kp.ValueChangedFcn = createCallbackFcn(app, @kpValueChanged, true);
            app.kp.Value = 10;
            app.protonLabel = uilabel(app.kneticparametersPanel);
            app.protonLabel.Text = 'proton';
            app.proton = uispinner(app.kneticparametersPanel);
            app.proton.Limits = [0 100];
            app.proton.Value = 20;
            app.Knob_27 = uiknob(app.kneticparametersPanel, 'continuous');
            app.Knob_27.Limits = [-4 2];
            app.Knob_27.MajorTicks = [-4 -3 -2 -1 0 1 2];
            app.Knob_27.MajorTickLabels = {'-4', '-3', '-2', '-1', '0', '1', '2'};
            app.Knob_27.ValueChangedFcn = createCallbackFcn(app, @Knob_27ValueChanged, true);
            app.Knob_27.Value = -1.7;
            app.grEditField_2Label_18 = uilabel(app.kneticparametersPanel);
            app.grEditField_2Label_18.Text = 'LOG';
            app.Knob_28 = uiknob(app.kneticparametersPanel, 'continuous');
            app.Knob_28.Limits = [-2 4];
            app.Knob_28.MajorTicks = [-2 -1 0 1 2 3 4];
            app.Knob_28.MajorTickLabels = {'-2', '-1', '0', '1', '2', '3', '4'};
            app.Knob_28.ValueChangedFcn = createCallbackFcn(app, @Knob_28ValueChanged, true);
            app.grEditField_2Label_19 = uilabel(app.kneticparametersPanel);
            app.grEditField_2Label_19.Text = 'LOG';
            app.Knob_29 = uiknob(app.kneticparametersPanel, 'continuous');
            app.Knob_29.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.Knob_29.ValueChangedFcn = createCallbackFcn(app, @Knob_29ValueChanged, true);
            app.Knob_29.Value = 20;
            app.thermoelectricTab = uitab(app.TabGroup);
            app.thermoelectricTab.Title = 'thermoelectric';
            app.tunnelingparametersPanel_2 = uipanel(app.thermoelectricTab);
            app.tunnelingparametersPanel_2.Title = 'tunneling parameters';
            app.Knob_30 = uiknob(app.tunnelingparametersPanel_2, 'continuous');
            app.Knob_30.Limits = [0 1];
            app.Knob_30.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.Knob_30.ValueChangedFcn = createCallbackFcn(app, @Knob_30ValueChanged, true);
            app.Knob_30.Value = 0.5;
            app.Knob_31 = uiknob(app.tunnelingparametersPanel_2, 'continuous');
            app.Knob_31.Limits = [0 0.1];
            app.Knob_31.MajorTicks = [0 0.02 0.04 0.06 0.08 0.1];
            app.Knob_31.ValueChangedFcn = createCallbackFcn(app, @Knob_31ValueChanged, true);
            app.Knob_31.Value = 0.005;
            app.Knob_32 = uiknob(app.tunnelingparametersPanel_2, 'continuous');
            app.Knob_32.Limits = [0 0.1];
            app.Knob_32.MajorTicks = [0 0.02 0.04 0.06 0.08 0.1];
            app.Knob_32.ValueChangedFcn = createCallbackFcn(app, @Knob_32ValueChanged, true);
            app.Knob_32.Value = 0.005;
            app.egLabel_7 = uilabel(app.tunnelingparametersPanel_2);
            app.egLabel_7.Text = 'eg';
            app.egEditField_7 = uispinner(app.tunnelingparametersPanel_2);
            app.egEditField_7.Step = 0.001;
            app.egEditField_7.Limits = [0 5];
            app.egEditField_7.Value = 0.5;
            app.grLabel_3 = uilabel(app.tunnelingparametersPanel_2);
            app.grLabel_3.Text = 'gr';
            app.grEditField_20 = uispinner(app.tunnelingparametersPanel_2);
            app.grEditField_20.Step = 0.001;
            app.grEditField_20.Limits = [0.001 5];
            app.grEditField_20.Value = 0.005;
            app.glLabel_3 = uilabel(app.tunnelingparametersPanel_2);
            app.glLabel_3.Text = 'gl';
            app.glEditField_7 = uispinner(app.tunnelingparametersPanel_2);
            app.glEditField_7.Step = 0.001;
            app.glEditField_7.Limits = [0.001 5];
            app.glEditField_7.Value = 0.005;
            app.voltagescalePanel_8 = uipanel(app.thermoelectricTab);
            app.voltagescalePanel_8.Title = 'voltage scale';
            app.voltagestepEditField_2Label = uilabel(app.voltagescalePanel_8);
            app.voltagestepEditField_2Label.Text = 'voltage step';
            app.voltagestepEditField_2 = uieditfield(app.voltagescalePanel_8, 'numeric');
            app.voltagestepEditField_2.Value = 100;
            app.Slider_9 = uislider(app.voltagescalePanel_8);
            app.Slider_9.Limits = [0 5];
            app.Slider_9.ValueChangedFcn = createCallbackFcn(app, @Slider_9ValueChanged, true);
            app.Slider_9.Value = 1.5;
            app.voltagescaleLabel_2 = uilabel(app.voltagescalePanel_8);
            app.voltagescaleLabel_2.Text = 'voltage scale';
            app.voltageEditField_10 = uispinner(app.voltagescalePanel_8);
            app.voltageEditField_10.Step = 0.1;
            app.voltageEditField_10.Limits = [0 5];
            app.voltageEditField_10.Value = 1.5;
            app.resultPanel_8 = uipanel(app.thermoelectricTab);
            app.resultPanel_8.Title = 'result';
            app.UIAxes_8 = uiaxes(app.resultPanel_8);
            title(app.UIAxes_8, '')
            xlabel(app.UIAxes_8, 'Voltage/V')
            ylabel(app.UIAxes_8, 'I/A')
            app.UIAxeslog_8 = uiaxes(app.resultPanel_8);
            title(app.UIAxeslog_8, '')
            xlabel(app.UIAxeslog_8, 'Voltage/V')
            ylabel(app.UIAxeslog_8, 'Log(I)')
            app.zerobiasthermocurrentPanel = uipanel(app.thermoelectricTab);
            app.zerobiasthermocurrentPanel.Title = 'zero bias thermocurrent ';
            app.Slider_13 = uislider(app.zerobiasthermocurrentPanel);
            app.Slider_13.Limits = [0 1000];
            app.Slider_13.MajorTicks = [0 100 200 300 400 500 600 700 800 900 1000];
            app.Slider_13.MajorTickLabels = {'0', '100', '200', '300', '400', '500', '600', '700', '800', '900', '1000'};
            app.Slider_13.ValueChangedFcn = createCallbackFcn(app, @Slider_13ValueChanged, true);
            app.Slider_13.MinorTicks = [];
            app.Slider_13.Value = 300;
            app.TdifferenceLabel = uilabel(app.zerobiasthermocurrentPanel);
            app.TdifferenceLabel.Text = 'T difference';
            app.alpha05EditField_7 = uispinner(app.zerobiasthermocurrentPanel);
            app.leftelectrodeLabel = uilabel(app.zerobiasthermocurrentPanel);
            app.leftelectrodeLabel.Text = 'left electrode';
            app.alpha05EditField_8 = uispinner(app.zerobiasthermocurrentPanel);
            app.alpha05EditField_8.Limits = [0 Inf];
            app.alpha05EditField_8.Value = 300;
            app.Slider_14 = uislider(app.zerobiasthermocurrentPanel);
            app.Slider_14.Limits = [0 1000];
            app.Slider_14.MajorTicks = [0 200 400 600 800 1000];
            app.Slider_14.ValueChangedFcn = createCallbackFcn(app, @Slider_14ValueChanged, true);
            app.Slider_14.MinorTicks = [];
            app.SimuButton_5 = uibutton(app.thermoelectricTab, 'push');
            app.SimuButton_5.ButtonPushedFcn = createCallbackFcn(app, @SimuButton_5Pushed, true);
            app.SimuButton_5.Text = 'Simu';
            app.Image2_8 = uiimage(app.thermoelectricTab);
            app.Image2_8.ImageSource = 'xiaohui.jpg';
            app.electrodetemperaturePanel = uipanel(app.thermoelectricTab);
            app.electrodetemperaturePanel.Title = 'electrode temperature';
            app.Slider_11 = uislider(app.electrodetemperaturePanel);
            app.Slider_11.Limits = [0 1000];
            app.Slider_11.MajorTicks = [0 100 200 300 400 500 600 700 800 900 1000];
            app.Slider_11.MajorTickLabels = {'0', '100', '200', '300', '400', '500', '600', '700', '800', '900', '1000'};
            app.Slider_11.ValueChangedFcn = createCallbackFcn(app, @Slider_11ValueChanged, true);
            app.Slider_11.MinorTicks = [];
            app.Slider_11.Value = 300;
            app.leftLabel = uilabel(app.electrodetemperaturePanel);
            app.leftLabel.Text = 'left';
            app.alpha05EditField_5 = uispinner(app.electrodetemperaturePanel);
            app.alpha05EditField_5.Limits = [0 Inf];
            app.alpha05EditField_5.Value = 300;
            app.rightLabel = uilabel(app.electrodetemperaturePanel);
            app.rightLabel.Text = 'right';
            app.alpha05EditField_6 = uispinner(app.electrodetemperaturePanel);
            app.alpha05EditField_6.Limits = [0 Inf];
            app.alpha05EditField_6.Value = 300;
            app.Slider_12 = uislider(app.electrodetemperaturePanel);
            app.Slider_12.Limits = [0 1000];
            app.Slider_12.MajorTicks = [0 100 200 300 400 500 600 700 800 900 1000];
            app.Slider_12.ValueChangedFcn = createCallbackFcn(app, @Slider_12ValueChanged, true);
            app.Slider_12.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Slider_12.Value = 300;
            app.homeButton = uibutton(app.UIFigure, 'push');
            app.homeButton.ButtonPushedFcn = createCallbackFcn(app, @homeButtonPushed, true);
            app.homeButton.Text = 'home';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'logo_1.png';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Image5Clicked, true);
            app.Image5.ImageSource = 'OIP-C.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end